classdef PlotSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Label1Panel                    matlab.ui.container.Panel
        GridLayout2_3                  matlab.ui.container.GridLayout
        MarkerTypeDropDown_2Label_2    matlab.ui.control.Label
        Comparison1MarkerTypeDropdown  matlab.ui.control.DropDown
        SizeLabel                      matlab.ui.control.Label
        Comparison1Size                matlab.ui.control.Spinner
        LineWidthSpinner_2Label        matlab.ui.control.Label
        Comparison1LineWidth           matlab.ui.control.Spinner
        ColorLabel_2                   matlab.ui.control.Label
        Comparison1ChooseColorButton   matlab.ui.control.Button
        Label2Panel                    matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        SizeLabel_2                    matlab.ui.control.Label
        Comparison2Size                matlab.ui.control.Spinner
        LineWidthSpinnerLabel          matlab.ui.control.Label
        Comparison2LineWidth           matlab.ui.control.Spinner
        ColorLabel                     matlab.ui.control.Label
        MarkerTypeDropDown_2Label      matlab.ui.control.Label
        Comparison2MarkerTypeDropdown  matlab.ui.control.DropDown
        Comparison2ChooseColorButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Icon = 'logo.png'
            app.MainApp = mainapp;
            app.Comparison1MarkerTypeDropdown.Value = app.MainApp.Session.ResultComparison1MarkerType;
            app.Comparison2MarkerTypeDropdown.Value = app.MainApp.Session.ResultComparison2MarkerType;            
            app.Comparison1LineWidth.Value = app.MainApp.Session.ResultComparison1LineWidth;
            app.Comparison2LineWidth.Value = app.MainApp.Session.ResultComparison2LineWidth;         
            app.Comparison1Size.Value = app.MainApp.Session.ResultComparison1Size;
            app.Comparison2Size.Value = app.MainApp.Session.ResultComparison2Size;
        end

        % Close request function: UIFigure
        function PlotSettingsClose(app, event)
            app.MainApp.PlotStyleSettings.Value = 0;
            app.MainApp.PlotSettings = [];
            delete(app)
        end

        % Value changed function: Comparison1LineWidth
        function Comparison1LineWidthChanged(app, event)
            value = app.Comparison1LineWidth.Value;
            app.MainApp.Session.ResultComparison1LineWidth = app.Comparison1LineWidth.Value; 
            app.MainApp.refreshPlot();
        end

        % Value changed function: Comparison2LineWidth
        function Comparison2LineWidthChanged(app, event)
            value = app.Comparison2LineWidth.Value;
            app.MainApp.Session.ResultComparison2LineWidth = app.Comparison2LineWidth.Value;
            app.MainApp.refreshPlot();
        end

        % Value changed function: Comparison1MarkerTypeDropdown
        function Comparison1MarkerTypeDropdownValueChanged(app, event)
            value = app.Comparison1MarkerTypeDropdown.Value;
            app.MainApp.Session.ResultComparison1MarkerType = app.Comparison1MarkerTypeDropdown.Value;
            app.MainApp.refreshPlot();
        end

        % Value changed function: Comparison2MarkerTypeDropdown
        function Comparison2MarkerTypeDropdownValueChanged(app, event)
            value = app.Comparison2MarkerTypeDropdown.Value;
            app.MainApp.Session.ResultComparison2MarkerType = app.Comparison2MarkerTypeDropdown.Value;
            app.MainApp.refreshPlot();
        end

        % Button pushed function: Comparison1ChooseColorButton
        function Comparison1ChooseColorButtonPushed(app, event)
            color = uisetcolor(app.MainApp.Session.ResultComparison1Color,'Select a color for label 1');
            app.MainApp.Session.ResultComparison1Color = color;
            app.MainApp.refreshPlot();
        end

        % Button pushed function: Comparison2ChooseColorButton
        function Comparison2ChooseColorButtonPushed(app, event)
            color = uisetcolor(app.MainApp.Session.ResultComparison1Color,'Select a color for label 2');
            app.MainApp.Session.ResultComparison2Color = color;
            app.MainApp.refreshPlot();
        end

        % Value changed function: Comparison1Size
        function Comparison1SizeValueChanged(app, event)
            value = app.Comparison1Size.Value;
            app.MainApp.Session.ResultComparison1Size = app.Comparison1Size.Value;
            app.MainApp.refreshPlot();
        end

        % Value changed function: Comparison2Size
        function Comparison2SizeValueChanged(app, event)
            value = app.Comparison2Size.Value;
            app.MainApp.Session.ResultComparison2Size = app.Comparison2Size.Value;
            app.MainApp.refreshPlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @PlotSettingsClose, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label2Panel = uipanel(app.GridLayout);
            app.Label2Panel.Title = 'Label 2';
            app.GridLayout2_2 = uigridlayout(app.Label2Panel);
            app.Comparison2ChooseColorButton = uibutton(app.GridLayout2_2, 'push');
            app.Comparison2ChooseColorButton.ButtonPushedFcn = createCallbackFcn(app, @Comparison2ChooseColorButtonPushed, true);
            app.Comparison2ChooseColorButton.Text = 'Choose Color';
            app.Comparison2MarkerTypeDropdown = uidropdown(app.GridLayout2_2);
            app.Comparison2MarkerTypeDropdown.Items = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            app.Comparison2MarkerTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @Comparison2MarkerTypeDropdownValueChanged, true);
            app.Comparison2MarkerTypeDropdown.Value = 'o';
            app.MarkerTypeDropDown_2Label = uilabel(app.GridLayout2_2);
            app.MarkerTypeDropDown_2Label.Text = 'Marker Type';
            app.ColorLabel = uilabel(app.GridLayout2_2);
            app.ColorLabel.Text = 'Color';
            app.Comparison2LineWidth = uispinner(app.GridLayout2_2);
            app.Comparison2LineWidth.Step = 0.5;
            app.Comparison2LineWidth.Limits = [0.5 Inf];
            app.Comparison2LineWidth.ValueChangedFcn = createCallbackFcn(app, @Comparison2LineWidthChanged, true);
            app.Comparison2LineWidth.Value = 1.5;
            app.LineWidthSpinnerLabel = uilabel(app.GridLayout2_2);
            app.LineWidthSpinnerLabel.Text = 'Line Width';
            app.Comparison2Size = uispinner(app.GridLayout2_2);
            app.Comparison2Size.Step = 20;
            app.Comparison2Size.Limits = [10 Inf];
            app.Comparison2Size.ValueChangedFcn = createCallbackFcn(app, @Comparison2SizeValueChanged, true);
            app.Comparison2Size.Value = 40;
            app.SizeLabel_2 = uilabel(app.GridLayout2_2);
            app.SizeLabel_2.Text = 'Size';
            app.Label1Panel = uipanel(app.GridLayout);
            app.Label1Panel.Title = 'Label 1';
            app.GridLayout2_3 = uigridlayout(app.Label1Panel);
            app.Comparison1ChooseColorButton = uibutton(app.GridLayout2_3, 'push');
            app.Comparison1ChooseColorButton.ButtonPushedFcn = createCallbackFcn(app, @Comparison1ChooseColorButtonPushed, true);
            app.Comparison1ChooseColorButton.Text = 'Choose Color';
            app.ColorLabel_2 = uilabel(app.GridLayout2_3);
            app.ColorLabel_2.Text = 'Color';
            app.Comparison1LineWidth = uispinner(app.GridLayout2_3);
            app.Comparison1LineWidth.Step = 0.5;
            app.Comparison1LineWidth.Limits = [0.5 Inf];
            app.Comparison1LineWidth.ValueChangedFcn = createCallbackFcn(app, @Comparison1LineWidthChanged, true);
            app.Comparison1LineWidth.Value = 1.5;
            app.LineWidthSpinner_2Label = uilabel(app.GridLayout2_3);
            app.LineWidthSpinner_2Label.Text = 'Line Width';
            app.Comparison1Size = uispinner(app.GridLayout2_3);
            app.Comparison1Size.Step = 20;
            app.Comparison1Size.Limits = [10 Inf];
            app.Comparison1Size.ValueChangedFcn = createCallbackFcn(app, @Comparison1SizeValueChanged, true);
            app.Comparison1Size.Value = 40;
            app.SizeLabel = uilabel(app.GridLayout2_3);
            app.SizeLabel.Text = 'Size';
            app.Comparison1MarkerTypeDropdown = uidropdown(app.GridLayout2_3);
            app.Comparison1MarkerTypeDropdown.Items = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            app.Comparison1MarkerTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @Comparison1MarkerTypeDropdownValueChanged, true);
            app.Comparison1MarkerTypeDropdown.Value = 'o';
            app.MarkerTypeDropDown_2Label_2 = uilabel(app.GridLayout2_3);
            app.MarkerTypeDropDown_2Label_2.Text = 'Marker Type';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end