classdef molecularFeaST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        MainGrid                        matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        InputDataTab                    matlab.ui.container.Tab
        InputDataTabGrid                matlab.ui.container.GridLayout
        ImportDataNextButton            matlab.ui.control.Button
        DisplayfulltableCheckBox        matlab.ui.control.CheckBox
        ImportedDataTable               matlab.ui.control.Table
        DataInfoPanel                   matlab.ui.container.Panel
        DataInfoPanelGridLayout         matlab.ui.container.GridLayout
        NumberOfSamplesLabel            matlab.ui.control.Label
        NumberOfSamplesLabelPrompt      matlab.ui.control.Label
        ImportValueCheckLabel           matlab.ui.control.Label
        ImportValueCheckLabelPrompt     matlab.ui.control.Label
        NumberOfGenesLabel              matlab.ui.control.Label
        NumberOfGenesLabelPrompt        matlab.ui.control.Label
        PathLabel                       matlab.ui.control.Label
        PathLabelPrompt                 matlab.ui.control.Label
        NumberofFeatureTypesLabel       matlab.ui.control.Label
        NumberofFeatureTypesLabelPrompt  matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        FilenameLabelPrompt             matlab.ui.control.Label
        ShowSampleDataButton            matlab.ui.control.Button
        ImportDataButton                matlab.ui.control.Button
        ComparisonsSelectionTab         matlab.ui.container.Tab
        ComparisonsSelectionTabGrid     matlab.ui.container.GridLayout
        DataSelectionNextButton         matlab.ui.control.Button
        DataSelectionBackButton         matlab.ui.control.Button
        ComparisonsListBox              matlab.ui.control.ListBox
        ShowComparisonsDropDown         matlab.ui.control.DropDown
        CategoricalLabelsListBox        matlab.ui.control.ListBox
        CategoricallabelsfrominputfileforcomparisonsLabel_2  matlab.ui.control.Label
        CategoricallabelsfrominputfileforcomparisonsLabel  matlab.ui.control.Label
        FeatureSelectionTab             matlab.ui.container.Tab
        FeatureSelectionTabNavigationGrid  matlab.ui.container.GridLayout
        RunButton                       matlab.ui.control.Button
        StayconnectedtoapowersourceLabel  matlab.ui.control.Label
        SetcomputersleepsettingstoNeverLabel  matlab.ui.control.Label
        FeatureSelectionBackButton      matlab.ui.control.Button
        FeatureSelectionPanel           matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        ParallelComputingButtonGroup    matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        OnButton                        matlab.ui.control.RadioButton
        SelectionSummaryPanel           matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        SummaryTextArea                 matlab.ui.control.TextArea
        Image2_15                       matlab.ui.control.Image
        iterationsforSequentialalgPanel  matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        IterationsDropDown              matlab.ui.control.DropDown
        IterationsLabel                 matlab.ui.control.Label
        Image2_16                       matlab.ui.control.Image
        CrossValidationButtonGroup      matlab.ui.container.ButtonGroup
        ResubButtonButton               matlab.ui.control.RadioButton
        LeaveoneoutButton               matlab.ui.control.RadioButton
        HoldoutButton                   matlab.ui.control.RadioButton
        KSpinner                        matlab.ui.control.Spinner
        KSpinnerLabel                   matlab.ui.control.Label
        KfoldButton                     matlab.ui.control.RadioButton
        SelectAlgorithmsPanel           matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        AlgorithmsCheckboxes            matlab.ui.container.GridLayout
        SeqQuadrLDACheckBox             matlab.ui.control.CheckBox
        SeqSvmCheckBox                  matlab.ui.control.CheckBox
        SeqEnsblCheckBox                matlab.ui.control.CheckBox
        SeqTreeCheckBox                 matlab.ui.control.CheckBox
        KnnCheckBox                     matlab.ui.control.CheckBox
        FictRusBoostCheckBox            matlab.ui.control.CheckBox
        FictGentleBoostCheckBox         matlab.ui.control.CheckBox
        FictBagCheckBox                 matlab.ui.control.CheckBox
        RankingusingdecisiontreeclassificationCheckBox  matlab.ui.control.CheckBox
        RankingusingrandomforestalgTreeBaggerCheckBox  matlab.ui.control.CheckBox
        RankingusingReliefFalgorithmCheckBox  matlab.ui.control.CheckBox
        WilcoxonCheckBox                matlab.ui.control.CheckBox
        RocCheckBox                     matlab.ui.control.CheckBox
        MutualInfoCheckBox              matlab.ui.control.CheckBox
        AllCheckBox                     matlab.ui.control.CheckBox
        GridLayout14                    matlab.ui.container.GridLayout
        Image2_14                       matlab.ui.control.Image
        Image2_13                       matlab.ui.control.Image
        Image2_12                       matlab.ui.control.Image
        Image2_11                       matlab.ui.control.Image
        Image2_10                       matlab.ui.control.Image
        Image2_9                        matlab.ui.control.Image
        Image2_8                        matlab.ui.control.Image
        Image2_7                        matlab.ui.control.Image
        Image2_6                        matlab.ui.control.Image
        Image2_5                        matlab.ui.control.Image
        Image2_4                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image2_17                       matlab.ui.control.Image
        ResultsTab                      matlab.ui.container.Tab
        ResultsTabGrid                  matlab.ui.container.GridLayout
        ResultsNavigationGrid           matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        ResultsBackButton               matlab.ui.control.Button
        SelectedFeaturesPanel           matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        CopyCurrentFeatureButton        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        RemoveAllFeatureButton          matlab.ui.control.Button
        RemoveCurrentFeatureButton      matlab.ui.control.Button
        AddCurrentFeatureButton         matlab.ui.control.Button
        FeaturesToClusterListBox        matlab.ui.control.ListBox
        FeaturesToClusterWaysButtonGroup  matlab.ui.container.ButtonGroup
        Image2_18                       matlab.ui.control.Image
        CustomButton                    matlab.ui.control.RadioButton
        PercentageToClusterDropDown     matlab.ui.control.DropDown
        Image2_19                       matlab.ui.control.Image
        PercentButton                   matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        PlotPanel                       matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        XFeatureActionsGrid             matlab.ui.container.GridLayout
        PredictiveImportancePlotButton  matlab.ui.control.Button
        RemoveXFeatureFromSelected      matlab.ui.control.Button
        AddXFeatureToSelected           matlab.ui.control.Button
        PlotStyleSettings               matlab.ui.control.StateButton
        LogTransformationButton         matlab.ui.control.StateButton
        RemoveXYFeatures                matlab.ui.control.Button
        YFeatureActionsGrid             matlab.ui.container.GridLayout
        RemoveYFeatureFromSelected      matlab.ui.control.Button
        AddYFeatureToSelected           matlab.ui.control.Button
        ResultsUIAxes                   matlab.ui.control.UIAxes
        ComparisonPanel                 matlab.ui.container.Panel
        ComparisonPanelGrid             matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        NextResultPage                  matlab.ui.control.Button
        ResultPageLabel                 matlab.ui.control.Label
        LastResultPage                  matlab.ui.control.Button
        PlotNextTwo                     matlab.ui.control.Button
        PlotPreviousTwo                 matlab.ui.control.Button
        ResultsTable                    matlab.ui.control.Table
        ExportResultsButton             matlab.ui.control.Button
        ViewResultsDropDown             matlab.ui.control.DropDown
        ClusteringTab                   matlab.ui.container.Tab
        ClusteringTabGrid               matlab.ui.container.GridLayout
        GenerateClustergram             matlab.ui.control.Button
        HierarchicalClusteringSettingsPanel  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        BoxplotButton                   matlab.ui.control.Button
        ComparisonLabel                 matlab.ui.control.Label
        SymmetricDropDown               matlab.ui.control.DropDown
        SymmetricDropDownLabel          matlab.ui.control.Label
        Image16_6                       matlab.ui.control.Image
        DisplayrangeSpinner             matlab.ui.control.Spinner
        DisplayrangeSpinnerLabel        matlab.ui.control.Label
        Image16_5                       matlab.ui.control.Image
        ColourmapDropDown               matlab.ui.control.DropDown
        ColourmapDropDownLabel          matlab.ui.control.Label
        Image16_3                       matlab.ui.control.Image
        LinkageDropDown                 matlab.ui.control.DropDown
        LinkageDropDownLabel            matlab.ui.control.Label
        Image16_4                       matlab.ui.control.Image
        FeaturessimilarityDropDown      matlab.ui.control.DropDown
        FeaturessimilarityDropDownLabel  matlab.ui.control.Label
        Image16_2                       matlab.ui.control.Image
        SamplessimilarityDropDown       matlab.ui.control.DropDown
        SamplessimilarityDropDownLabel  matlab.ui.control.Label
        Image16                         matlab.ui.control.Image
        AboutTab                        matlab.ui.container.Tab
        AboutTabGrid                    matlab.ui.container.GridLayout
        TollboxGrid                     matlab.ui.container.GridLayout
        StatisticsandMachineLearningToolboxLamp_2Label  matlab.ui.control.Label
        StatisticsandMachineLearningToolboxLamp  matlab.ui.control.Lamp
        ParallelComputingToolboxLampLabel  matlab.ui.control.Label
        ParallelComputingToolboxLamp    matlab.ui.control.Lamp
        DeepLearningToolboxLampLabel    matlab.ui.control.Label
        DeepLearningToolboxLamp         matlab.ui.control.Lamp
        BioinformaticsToolboxLampLabel  matlab.ui.control.Label
        BioinformaticsToolboxLamp       matlab.ui.control.Lamp
        HelpGrid                        matlab.ui.container.GridLayout
        UserManualButton                matlab.ui.control.Button
        VersionGrid                     matlab.ui.container.GridLayout
        UpdatenotesLabel                matlab.ui.control.Label
        CurrentVersion                  matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        AboutGrid                       matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        ToolboxesLabel                  matlab.ui.control.Label
        HelpLabel                       matlab.ui.control.Label
        VersionLabel                    matlab.ui.control.Label
        AboutLabel                      matlab.ui.control.Label
        MolecularFeaSTLabel_2           matlab.ui.control.Label
        bg                              matlab.ui.control.Image
        SessionDropDownGrid             matlab.ui.container.GridLayout
        SessionDropDown                 matlab.ui.control.DropDown
        SessionLabel                    matlab.ui.control.Label
        LogoGrid                        matlab.ui.container.GridLayout
        MolecularFeaSTLabel             matlab.ui.control.Label
        Logo                            matlab.ui.control.Image
    end

    
    properties (Access = public)
        %%% publically accessible variables
        %%% can be passed to other mlapp and let them access

        Session;
        PlotSettings = [];
    end
    
    properties (Access = private)
        %%% private variables that can only be accessed within this mlapp
        
        % current version
        Version = '1.3';
        % link to the update the program
        VersionLink = 'https://google.com';
        % link to the manual of the program
        ManualLink = 'https://google.com';
        % link to the update info file
%         CheckForUpdateLink = 'https://raw.githubusercontent.com/rickyzhangca/test/main/version.json';
        
        % variables        
        currentTab;
        HasBioInfoToolbox = false;
        HasDeepLearningToolbox = false;
        HasParallelComputingToolbox = false;
        HasStatToolbox = false;
        TsneDisplayed = false;
        SessionOpenPath = '';
        
    end
    
    methods (Access = private)        
        %%% private functions that can only be accessed within this mlapp
        
        %%% check if any value is negative
        %%% to prevent from negative values causing errors in log transformation
        function pass = valueCheck(app,data)
            pass = 0;
            data = cell2mat(data(app.Session.NumberOfComparisonTypes + 2:end,2:end));
            % if the data does not contain anything < 0 then pass
            if ~any(any(data<0)); pass = 1; end
        end
        
        %%% take snapshot of table
        %%% reduce the cells to display for faster loading
        %%% used in import data tab
        function snapshot = snapshotTable(app, dataToSnapshot)
            snapshot = dataToSnapshot;
            if app.Session.InputDataShowFullTable == 0
                [rows,cols] = size(dataToSnapshot);
                if rows > 25 && cols > 10
                    if app.DisplayfulltableCheckBox.Value == 0
                        snapshot = dataToSnapshot(1:25,1:10);
                    end
                end
            end
        end       
        
        
        %%% update summary textarea with any changes to values
        function updateSummary(app)
            if strcmp(app.Session.Comparison1, '') || strcmp(app.Session.Comparison2, '')
                app.SummaryTextArea.Value = "No comparison selected yet";

%             elseif ~isempty(app.Session.ClusteringComparison1) && ~isempty(app.Session.ClusteringComparison2)
%                 comparison = app.Session.ClusteringComparison1 + " vs " + app.Session.ClusteringComparison2;
            else
                % X vs Y
                comparison = app.Session.Comparison1 + " vs " + app.Session.Comparison2;
            end
            % configurations and algorithms to run
            if isempty(app.Session.FeatureSelectionAlgorithmsToRun)
                app.SummaryTextArea.Value = comparison + newline + "No algorithm selected yet";
            else
                crossVal = "Cross-Validation: " + app.CrossValidationButtonGroup.SelectedObject.Text;
                if app.CrossValidationButtonGroup.SelectedObject == app.KfoldButton
                    crossVal = crossVal + " with K = " + app.KSpinner.Value;
                end

                parallelComp = "Parallel Computing: " + app.ParallelComputingButtonGroup.SelectedObject.Text;

                algs = "Algorithms Selected: ";

                % check if all is selected, loop through array of checkboxes
                % and then add them one by one
                if app.AllCheckBox.Value == 1
                    algs = algs + "All algorithms selected";
                else
                    % get checkboxes
                    allCheckBox = allchild(app.AlgorithmsCheckboxes);
                    % remove AllCheckBox from array
                    remainingCheckBoxes = allCheckBox(allCheckBox ~= app.AllCheckBox);
                    numCheckBoxes = size(remainingCheckBoxes);
                    % check checkboxes
                    for i = 1:numCheckBoxes
                        if remainingCheckBoxes(i).Value == 1
                            algs = algs + remainingCheckBoxes(i).Text + ", ";
                        end
                    end
                end
                % if any of the Sequential algorithms are selected
                iter = '';
                if strcmp(app.iterationsforSequentialalgPanel.Enable,'on')
                    iter = "Iterations: " + string(app.IterationsDropDown.Value);
                end
                % update summary text
                app.SummaryTextArea.Value = comparison + newline + newline ...
                    + algs +  newline + newline + crossVal + newline + newline + parallelComp + newline + iter;
            end
        end
        
        %%% update input data tab
        function updateInputDataTab(app)
            % update data info - file and path
            app.FilenameLabel.Text = string(app.Session.InputFileName);
            app.PathLabel.Text = string(app.Session.InputDataPath);
            % update data info - counters
            app.NumberofFeatureTypesLabel.Text = string(app.Session.NumberOfComparisonTypes);
            app.NumberOfGenesLabel.Text = string(app.Session.NumberOfGenes);
            app.NumberOfSamplesLabel.Text = string(app.Session.NumberOfSamples); 
            % update data info - value checks    
            app.ImportValueCheckLabel.Text = app.Session.InputDataCheck;
            app.ImportValueCheckLabel.FontColor = app.Session.InputDataCheckColor;  
            % update table  
            app.DisplayfulltableCheckBox.Value = app.Session.InputDataShowFullTable;
            set(app.ImportedDataTable, 'Data', snapshotTable(app,app.Session.InputData), 'ColumnEditable', [true, false]);
            % update log tranformation status
            if app.Session.InputDataLogTransformed
                app.LogTransformationButton.Enable = 'off';
            else 
                app.LogTransformationButton.Enable = 'on';
            end
            
        end
        
        %%% update input data tab clean comparisons selection tab feature typebox
        function cleanComparisonsSelectionTabFeatureTypeBox(app)
            % update feature types ("categorical labels")
            % using 2 instead of 1 due to the first row being the header row
            app.CategoricalLabelsListBox.Items = app.Session.InputData(2:app.Session.NumberOfComparisonTypes + 1, 1)';
            app.CategoricalLabelsListBox.ItemsData = (2:app.Session.NumberOfComparisonTypes + 1);
            app.CategoricalLabelsListBox.Value = 2;
            app.Session.ComparisonFeatureType = 2;
            % clear comparison dropdown and box
            app.ShowComparisonsDropDown.Items = {'Show all comparisons'};
            app.Session.ComparisonDropDownChoice = 'Show all comparisons';
            app.ShowComparisonsDropDown.Value = app.Session.ComparisonDropDownChoice;
            for i = 1:length(app.ComparisonsListBox.Items)
                app.ComparisonsListBox.Items(1) = [];
            end
        end
        
        %%% update comparison selection tab
        function updateComparisonsSelectionTab(app)
            if app.Session.InputDataImported
                % update feature types ("categorical labels")
                app.CategoricalLabelsListBox.Items = app.Session.InputData(2:app.Session.NumberOfComparisonTypes + 1, 1)';
                app.CategoricalLabelsListBox.ItemsData = (2:app.Session.NumberOfComparisonTypes + 1);
                app.CategoricalLabelsListBox.Value = app.Session.ComparisonFeatureType;
                % load comparison dropdown and box
                populateCompListBox(app);  
            end
        end
        
        %%% update feature selection tab
        function updateFeatureSelectionTab(app) 
            % get all checkboxes except the select all
            allAlgorithms = allchild(app.AlgorithmsCheckboxes);
            allAlgorithms = allAlgorithms(allAlgorithms ~= app.AllCheckBox);
            % check which algorithms are selected
            for i = 1:size(allAlgorithms)
                if any(strcmp(allAlgorithms(i).Tag, app.Session.FeatureSelectionAlgorithmsToRun))
                    allAlgorithms(i).Value = 1;
                else
                    allAlgorithms(i).Value = 0;
                end
            end
            % if all are selcted, check the check all checkbox
            if length(allAlgorithms) == length(app.Session.FeatureSelectionAlgorithmsToRun)
                app.AllCheckBox.Value = 1;
            else
                app.AllCheckBox.Value = 0;
            end
            
            % cross-validation settings
            allValidations = allchild(app.CrossValidationButtonGroup);
            for i = 1:size(allValidations)
                if any(strcmp(allValidations(i).Tag, app.Session.FeatureSelectionCrossValidationType))
                    allValidations(i).Value = 1;
                    break
                end
            end
            app.KSpinner.Value = app.Session.FeatureSelectionKfoldK;
            % parallel computing settings
            if app.Session.FeatureSelectionParallelComputing
                app.OnButton.Value = 1;
            else
                app.OffButton.Value = 1;
            end
            % iteration settings
            app.IterationsDropDown.Value = {app.Session.FeatureSelectionIterations};
            
            % update panels
            updateFeatureSelectionTabHelper(app);
        end
        
        %%% update feature selection tab helper
        %%% simply update the configurations panels: enable if is needed + toolbox installed
        function updateFeatureSelectionTabHelper(app)
            % update components availability
            toEnableParallelComputing = 'off';
            toEnableCrossValidation = 'off';
            toEnableIterations = 'off';
            % check what's needed for the algorithms enabled
            if ~isempty(app.Session.FeatureSelectionAlgorithmsToRun)
                toEnableParallelComputing = 'on';
                toEnableCrossValidation = 'on';
            end
            if any(contains(app.Session.FeatureSelectionAlgorithmsToRun, 'Sequential'))
                toEnableIterations = 'on';
            end
            % update availibility for all algorithms
            if ~(app.HasDeepLearningToolbox); app.SelectAlgorithmsPanel.Enable = 'off'; end
            % update availibility for parallel computing settings
            if (app.HasParallelComputingToolbox); app.ParallelComputingButtonGroup.Enable = toEnableParallelComputing; end
            % update availibility for cross validation settings
            app.CrossValidationButtonGroup.Enable = toEnableCrossValidation;            
            % update availibility for # of iterations settings
            app.iterationsforSequentialalgPanel.Enable = toEnableIterations;
        end
        
        %%% update result tab
        function updateResultTab(app)
            % update view options dropdown
            app.ViewResultsDropDown.Value = app.Session.ResultViewing;
            % update result table
            app.ResultsTable.ColumnName = 'numbered';
            app.ResultsTable.RowName = 'numbered';
            updateResultPagination(app);
            
            % update comparison label          
%             app.Session.ClusteringComparison1 = app.Session.Comparison1;
%             app.Session.ClusteringComparison2 = app.Session.Comparison2;
            if isequal(app.Session.ResultRankedCellData,{}) || isequal(app.Session.ResultRankedFeatures,{})
                app.ComparisonPanel.Title = "Please run the feature selection first.";
                app.GenerateClustergram.Enable ='off';
            else
                clusteringLabels = unique(app.Session.ResultRankedCellData(1,2:end));
                app.Session.ClusteringComparison1 = clusteringLabels{1};
                app.Session.ClusteringComparison2 = clusteringLabels{2};
                app.ComparisonPanel.Title = app.Session.ClusteringComparison1 + " vs " + app.Session.ClusteringComparison2;
                app.GenerateClustergram.Enable ='on';
            end            
            
            % update plotting
            app.LogTransformationButton.Value = app.Session.ResultPlotLogTransformed;
            temp = app.Session.ResultPlottedFeatures;
            cleanPlot(app);
            app.Session.ResultPlottedFeatures = [];
            for i = 1:length(temp)
                addToPlot(app,temp(i));
            end
            
            % update features selection checkboxes and dropdown
            allFeaturesToClusterWays = allchild(app.FeaturesToClusterWaysButtonGroup);
            for i = 1:size(allFeaturesToClusterWays)
                if any(strcmp(allFeaturesToClusterWays(i).Tag, app.Session.ResultsFeaturesToClusterWay))
                    allFeaturesToClusterWays(i).Value = 1;
                    break
                end
            end
            app.PercentageToClusterDropDown.Value = app.Session.ResultsFeaturesToClusterPercentage;  
            
            % update feature to cluster listbox (clean it first)
            % if has some feature(s) selected, color the result table for them
            for i = 1:length(app.FeaturesToClusterListBox.Items)
                app.FeaturesToClusterListBox.Items(1) = [];
                app.FeaturesToClusterListBox.ItemsData(1) = [];
            end            
            if ~isempty(app.Session.ResultSelectedFeatures) && ~isempty(app.Session.ResultSelectedFeaturesData)
                app.FeaturesToClusterListBox.Items = app.Session.ResultSelectedFeatures;
                app.FeaturesToClusterListBox.ItemsData = app.Session.ResultSelectedFeaturesData;                
                recolorResultTabTable(app); % recolor the results table
            end
            
            % if has some feature(s) selected, enable the remove feature selected buttons
            if (~isempty(app.FeaturesToClusterListBox.ItemsData))
                app.RemoveCurrentFeatureButton.Enable = 'on';
                app.RemoveAllFeatureButton.Enable = 'on';
            end
            
            app.Session.ResultCurrentPage = 1;
            updateResultPagination(app);
        end
        
        %%% recolor the results table
        function recolorResultTabTable(app) 
            % remove the existing styles
            removeStyle(app.ResultsTable);
            % color the table row(s)
            % left and right are the boundary of the current page in the full result table
            left = (app.Session.ResultCurrentPage - 1) * app.Session.ResultRowsPerPage + 2;
            right = app.Session.ResultCurrentPage * app.Session.ResultRowsPerPage + 1;
            
            % if has some feature(s) selected, color the rows
            if ~isempty(app.Session.ResultSelectedFeaturesData)
                rows = cell2mat(app.Session.ResultSelectedFeaturesData);
                toColor = [];
                for i = 1:length(rows)
                    if rows(i) >= left && rows(i) <= right
                        % get the real row of the feature from the page row
                        % -1 since the header row is cut out
                        toColor = [toColor,mod(rows(i) - 1,app.Session.ResultRowsPerPage)];
                    end
                end
                if ~isempty(toColor)
                    % 0 means the last one (<- quotient = 0 after mod calcualtion)
                    if toColor(end) == 0; toColor(end) = app.Session.ResultRowsPerPage; end
                    colorRows(app,toColor); 
                end
            end
            
            % if has some feature(s) selected for plotting, bold the row(s)
            if ~isempty(app.Session.ResultPlottedFeatures)
                rows = app.Session.ResultPlottedFeatures;
                toBold = [];
                for i = 1:length(rows)
                    if rows(i) >= left && rows(i) <= right
                        toBold = [toBold,mod(rows(i) - 1,app.Session.ResultRowsPerPage)]; % -1 since the header row is cut out
                    end
                end
                if ~isempty(toBold); boldRows(app,toBold); end
            end
        end
        
        %%% workaround of reverse selecting 
        %%% select in selected features listbox -> color in result table
        function selectRow(app,row)
            style = uistyle('BackgroundColor','#93D5FC');
            addStyle(app.ResultsTable,style,'row',row);
        end
        
        %%% update the clustering tab 
        function updateClusteringTab(app)
            % update clustering params
            app.SamplessimilarityDropDown.Value = app.Session.ClusteringSamplesSimilarity;
            app.FeaturessimilarityDropDown.Value = app.Session.ClusteringFeaturesSimilarity;
            app.LinkageDropDown.Value = app.Session.ClusteringLinkage;
            app.ColourmapDropDown.Value = app.Session.ClusteringColourMap;
            app.DisplayrangeSpinner.Value = app.Session.ClusteringDisplayRange;
            
            if isequal(app.Session.ResultRankedCellData,{}) || isequal(app.Session.ResultRankedFeatures,{})
                app.ComparisonLabel.Text = "Please run the feature selection first.";
                app.GenerateClustergram.Enable ='off';
            else
                app.ComparisonLabel.Text = app.Session.ClusteringComparison1 + " vs " + app.Session.ClusteringComparison2;
                app.GenerateClustergram.Enable ='on';
            end
            
        end
        
        %%% add feature(s) to feature to cluster listbox
        %%% rows: array of row # of the features to add
        function addToFeatureToClusterListbox(app,rows)
            % exclude the header row and the features tha have been added
            toAdd = setdiff(rows,[1]);
            toAdd = setdiff(toAdd,cell2mat(app.FeaturesToClusterListBox.ItemsData));
            % return if no feature to add
            if isempty(toAdd); return; end
            % for each feature to add:
            for i = 1:length(toAdd)
                % get feature name
                featureName = char(app.Session.ResultRankedCellData(toAdd(i)));
                featureName = sprintf('%s', strrep(featureName, char(39), ''));
                % add to feature name and row number to listbox
                app.FeaturesToClusterListBox.Items = ...
                    [app.FeaturesToClusterListBox.Items, featureName];
                % add "feature data": row number
                % featureData = app.Session.ResultRankedCellData(app.Session.ResultSelectedIndices(1), 1:end);
                app.FeaturesToClusterListBox.ItemsData{end+1} = toAdd(i);
            end  
            % sync with session
            app.Session.ResultSelectedFeatures = app.FeaturesToClusterListBox.Items;
            app.Session.ResultSelectedFeaturesData = app.FeaturesToClusterListBox.ItemsData;
            % select and scroll to the feature added in listbox
            app.FeaturesToClusterListBox.Value = {toAdd(end)};
            scroll(app.FeaturesToClusterListBox,toAdd(end));
            % enable the remove buttons if added
            if (~isempty(app.FeaturesToClusterListBox.ItemsData))
                app.RemoveCurrentFeatureButton.Enable = 'on';
                app.RemoveAllFeatureButton.Enable = 'on';
            end
            % recolor table
            recolorResultTabTable(app);
        end        
        
        %%% add feature(s) to feature to cluster listbox
        %%% convert tge row # on page to real row # in result table
        function addToFeatureToClusterListboxViaPagination(app,rows)
            for i = 1:length(rows)
                % remove page pagination
                % +1 due to pagination
                rows(i) = (app.Session.ResultCurrentPage - 1) * app.Session.ResultRowsPerPage + rows(i) + 1;
            end  
            addToFeatureToClusterListbox(app,rows);
        end
        
        %%% color rows
        %%% rows: array of row # to be colored
        function colorRows(app,rows)
            % color the table row
            style = uistyle('BackgroundColor','#E4E5EF');
            addStyle(app.ResultsTable,style,'row',rows);
        end
        
        %%% color rows
        %%% rows: array of row # to be bolded
        function boldRows(app,rows)
            % bolden the table row
            style = uistyle('FontColor','#37519D','FontWeight','bold');
            addStyle(app.ResultsTable,style,'row',rows);
        end
        
        %%% remove some feature(s) from feature to cluster listbox
        function removeFromFeatureToClusterListbox(app,rows)
            % if manually removing -> custom feature selection mode
            if isempty(app.FeaturesToClusterListBox.ItemsData(:)); return; end
            if ~strcmp(app.Session.ResultsFeaturesToClusterWay,'custom')                
                app.CustomButton.Value = 1;
                % update to session
                app.Session.ResultsFeaturesToClusterWay = app.CustomButton.Tag;
            end
            
            % get itemdata to remove
            toRemove = (sort(rows,'descend'));
            if isempty(toRemove)
                return
            end
            %remove items
            index = 0;
            for i = 1:length(toRemove)            
                % get index (with itemsdata filled, .value is now one of the itemsdata)
                % [cell_array{:}] method from stackoverflow.com/questions/23695955
                [found,index] = ismember(toRemove(i),[app.FeaturesToClusterListBox.ItemsData{:}]);
                if found == 0
                    return % feature not added yet
                end
                % remove from items and itemsdata
                app.FeaturesToClusterListBox.Items(index) = [];
                app.FeaturesToClusterListBox.ItemsData(index) = [];
                % select the next feature
                if ~isempty(app.FeaturesToClusterListBox.ItemsData)
                    if index <= length(app.FeaturesToClusterListBox.ItemsData)
                        nextItem = app.FeaturesToClusterListBox.ItemsData(index);
                    else 
                        nextItem = app.FeaturesToClusterListBox.ItemsData(index - 1);
                    end
                end       
            end
            % if list not empty, select the next item for user
            if ~isempty(app.FeaturesToClusterListBox.Items) 
                app.FeaturesToClusterListBox.Value = nextItem;
                scroll(app.FeaturesToClusterListBox,cell2mat(nextItem));   
            end
            % sync with session
            app.Session.ResultSelectedFeatures = app.FeaturesToClusterListBox.Items;
            app.Session.ResultSelectedFeaturesData = app.FeaturesToClusterListBox.ItemsData;
            % recolor the table
            recolorResultTabTable(app);
        end
        
        %%% remove all features from feature to cluster listbox
        function clearFeatureToClusterListbox(app)
            % remove all features selected for clustering
            for i = 1:length(app.FeaturesToClusterListBox.Items)
                app.FeaturesToClusterListBox.Items(1) = [];
                app.FeaturesToClusterListBox.ItemsData(1) = [];
            end
            % sync with session
            app.Session.ResultSelectedFeatures = app.FeaturesToClusterListBox.Items;
            app.Session.ResultSelectedFeaturesData = app.FeaturesToClusterListBox.ItemsData;
            % recolor the table
            recolorResultTabTable(app);
            % disable remove feature buttons
            app.RemoveCurrentFeatureButton.Enable = 'off';
            app.RemoveAllFeatureButton.Enable = 'off';
        end
        
        %%% update about tab
        function updateAboutTab(app)
            % update the lamps
            if app.HasBioInfoToolbox; app.BioinformaticsToolboxLamp.Color = '#7BCF42'; end
            if app.HasDeepLearningToolbox; app.DeepLearningToolboxLamp.Color = '#7BCF42'; end
            if app.HasParallelComputingToolbox; app.ParallelComputingToolboxLamp.Color = '#7BCF42'; end
            if app.HasStatToolbox; app.StatisticsandMachineLearningToolboxLamp.Color = '#7BCF42'; end
        end
        
        %%% add a feature to the plot %THIS FUNCTION NEEDS TO BE SIMPLIFIED
        function addToPlot(app,rows)   
            % exclude any other rows and the plotted rows
            toAdd = setdiff(rows,1);
            toAdd = setdiff(toAdd,app.Session.ResultPlottedFeatures);
            % if no feature to plot, return
            if isempty(toAdd); return; end
            toAdd = toAdd(1);
            
            % prepare
            [r,c] = size(app.Session.ResultRankedCellData);
            comparison1x = [];
            comparison2x = [];
            comparison1y = [];
            comparison2y = [];
            cleanPlot(app); 
            hold(app.ResultsUIAxes,'on');      
            % if adding the second feature
            if length(app.Session.ResultPlottedFeatures) == 1  
                name1 = string(app.Session.ResultRankedCellData(app.Session.ResultPlottedFeatures(1),1));
                name1 = sprintf('%s', strrep(name1, char(39), ''));
                name2 = string(app.Session.ResultRankedCellData(toAdd,1));
                name2 = sprintf('%s', strrep(name2, char(39), ''));
                comparison1x = cell2mat(app.Session.ResultRankedCellData(app.Session.ResultPlottedFeatures(1),2:end));
                comparison1y = cell2mat(app.Session.ResultRankedCellData(toAdd,2:end));
%                 for j = 2:c
%                     if strcmp(string(app.Session.ResultRankedCellData(1,j)),app.Session.ClusteringComparison1)
%                         comparison1y = [comparison1y,cell2mat(app.Session.ResultRankedCellData(app.Session.ResultPlottedFeatures(1),j))];
%                         comparison1x = [comparison1x,cell2mat(app.Session.ResultRankedCellData(toAdd,j))];
%                     else
%                         comparison2y = [comparison2y,cell2mat(app.Session.ResultRankedCellData(app.Session.ResultPlottedFeatures(1),j))];
%                         comparison2x = [comparison2x,cell2mat(app.Session.ResultRankedCellData(toAdd,j))];
%                     end
%                 end
                % setup x axis
                xlabel(app.ResultsUIAxes,name2,'Color','#000000');
                app.AddXFeatureToSelected.Enable = 'on';
                app.RemoveXFeatureFromSelected.Enable = 'on';
                % setup y axis
                ylabel(app.ResultsUIAxes,name1,'Color','#000000');
                app.AddYFeatureToSelected.Enable = 'on';
                app.RemoveYFeatureFromSelected.Enable = 'on';
            % if adding the first feature    
            else % if length(app.Session.ResultPlottedFeatures) == 0 or 2
                % clean the features plotted previously
                app.Session.ResultPlottedFeatures = [];      
                % get feature name
                name1 = string(app.Session.ResultRankedCellData(toAdd,1));
                name1 = sprintf('%s', strrep(name1, char(39), ''));  
                name2 = 'Samples';
                comparison1x = 1:length(app.Session.ResultRankedCellData(toAdd,2:end));
                comparison1y = cell2mat(app.Session.ResultRankedCellData(toAdd,2:end));
                % setup x axis
                xlabel(app.ResultsUIAxes,name2,'Color','#000000');
                app.AddXFeatureToSelected.Enable = 'off';
                app.RemoveXFeatureFromSelected.Enable = 'off';
                % setup y axis
                ylabel(app.ResultsUIAxes,name1,'Color','#000000'); 
                app.AddYFeatureToSelected.Enable = 'on';
                app.RemoveYFeatureFromSelected.Enable = 'on';
            end  
            % add legend
            %             legend(app.ResultsUIAxes,'-DynamicLegend');
            % process log transform
            if app.Session.ResultPlotLogTransformed == 1
                ylabel(app.ResultsUIAxes,string(app.ResultsUIAxes.YLabel.String) + '  (Log2)');
                comparison1y = log2(replaceZeros(comparison1y, 'lowval')); %log transform
                if ~contains(name2,'Samples')
                    xlabel(app.ResultsUIAxes,string(app.ResultsUIAxes.XLabel.String) + '  (Log2)');
                    comparison1x = log2(replaceZeros(comparison1x, 'lowval')); %log transform
                end
            end
            % plot
%             set(app.ResultsUIAxes, 'DefaultTextInterpreter', 'none');
            gscatter (app.ResultsUIAxes,comparison1x,comparison1y, ...
                categorical(regexprep(app.Session.ResultRankedCellData(1,2:end),'_','-')), ...
                [app.Session.ResultComparison1Color;app.Session.ResultComparison2Color], ...
                [app.Session.ResultComparison1MarkerType app.Session.ResultComparison2MarkerType],...
                [app.Session.ResultComparison1Size app.Session.ResultComparison2Size], ...
                'on');
            axis(app.ResultsUIAxes,'tight');

%             if app.Session.ResultPlotLogTransformed == 1
%                 xlim(app.ResultsUIAxes,[app.ResultsUIAxes.XLim(1) - app.ResultsUIAxes.XLim(2)/10, app.ResultsUIAxes.XLim(2) + app.ResultsUIAxes.XLim(2)/10]);
%                 ylim(app.ResultsUIAxes,[app.ResultsUIAxes.YLim(1) - app.ResultsUIAxes.YLim(2)/10, app.ResultsUIAxes.YLim(2) + app.ResultsUIAxes.YLim(2)/10]);
%             end

%             scatter(app.ResultsUIAxes,comparison1x,comparison1y, ...
%                 app.Session.ResultComparison1Size, ...
%                 app.Session.ResultComparison1Color, ...
%                 'LineWidth',app.Session.ResultComparison1LineWidth, ...
%                 'DisplayName',app.Session.ClusteringComparison1, ...
%                 'Marker',app.Session.ResultComparison1MarkerType);
%             scatter(app.ResultsUIAxes,comparison2x,comparison2y, ...
%                 app.Session.ResultComparison2Size, ...
%                 app.Session.ResultComparison2Color, ...
%                 'LineWidth',app.Session.ResultComparison2LineWidth, ...
%                 'DisplayName',app.Session.ClusteringComparison2, ...
%                 'Marker',app.Session.ResultComparison2MarkerType);    
%             axis(app.ResultsUIAxes,'tight');
%             xlim(app.ResultsUIAxes,[app.ResultsUIAxes.XLim(1) - app.ResultsUIAxes.XLim(2)/10, app.ResultsUIAxes.XLim(2) + app.ResultsUIAxes.XLim(2)/10]);
%             ylim(app.ResultsUIAxes,[app.ResultsUIAxes.YLim(1) - app.ResultsUIAxes.YLim(2)/10, app.ResultsUIAxes.YLim(2) + app.ResultsUIAxes.YLim(2)/10]);
%             hold(app.ResultsUIAxes,'off');
            app.Session.ResultPlottedFeatures = [app.Session.ResultPlottedFeatures, toAdd];
            recolorResultTabTable(app);
            % check if plot next 2 or plot previous 2 is possible
            if sum(app.Session.ResultPlottedFeatures) == r * 2 - 1; app.PlotNextTwo.Enable = 'off'; else app.PlotNextTwo.Enable = 'on'; end
            if sum(app.Session.ResultPlottedFeatures) == 2 + 3; app.PlotPreviousTwo.Enable = 'off'; else app.PlotPreviousTwo.Enable = 'on'; end
        end
        
        %%% add feature(s) to plot from page
        %%% converts row # on page to row # on real result table
        function addToPlotViaPagination(app,row)
            row = (app.Session.ResultCurrentPage - 1) * app.Session.ResultRowsPerPage + row + 1; 
            app.addToPlot(row);
        end
        
        %%% clean plot
        function cleanPlot(app)
            % clean plotted graphs
            cla(app.ResultsUIAxes);
            % clean x axis
            set(app.ResultsUIAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
            xlabel(app.ResultsUIAxes,'Select a feature','Color','#9C9C9C');    
            app.AddXFeatureToSelected.Enable = 'off'; 
            app.RemoveXFeatureFromSelected.Enable = 'off';       
            % clean y axis
            set(app.ResultsUIAxes, 'YTickMode', 'auto', 'YTickLabelMode', 'auto');
            ylabel(app.ResultsUIAxes,'Select a feature','Color','#9C9C9C');  
            app.AddYFeatureToSelected.Enable = 'off';
            app.RemoveYFeatureFromSelected.Enable = 'off';
            % disable previous 2 and next 2 buttons
            app.PlotNextTwo.Enable = 'off';
            app.PlotPreviousTwo.Enable = 'off';
        end
        
        %%% update result pages
        %%% will regenerate them
        function updateResultPagination(app)
            % clean table
            app.ResultsTable.Data = {};
            % return if no results to show
            if isempty(app.Session.ResultRankedFeatures) || isempty(app.Session.ResultRankedCellData); return; end
            % get data to show
            if strcmp(app.Session.ResultViewing, 'Ranked features')
                data = app.Session.ResultRankedFeatures(:,1:7);                
            else % strcmp(app.Session.ResultViewing, 'Ranked cell data')                
                data = app.Session.ResultRankedCellData;
            end
            % get left and boundaries
            left = (app.Session.ResultCurrentPage - 1) * app.Session.ResultRowsPerPage + 2;
            right = app.Session.ResultCurrentPage * app.Session.ResultRowsPerPage + 1;
            [rows,~] = size(data);
            app.Session.ResultTotalPages = ceil(rows/app.Session.ResultRowsPerPage);
            if right > rows; right = rows; end
            if left < 2; left = 2; end
            % slice data
            app.ResultsTable.Data = data(left:right,:);
            app.ResultsTable.ColumnName = data(1:1,:);
            app.ResultPageLabel.Text = string(app.Session.ResultCurrentPage) + '/' + string(app.Session.ResultTotalPages);
            % enable plot next 2 or plot previous 2 if possible
            if app.Session.ResultCurrentPage < app.Session.ResultTotalPages; app.NextResultPage.Enable = 'on'; else app.NextResultPage.Enable = 'off'; end
            if app.Session.ResultCurrentPage > 1; app.LastResultPage.Enable = 'on'; else app.LastResultPage.Enable = 'off'; end
            % color the table
            recolorResultTabTable(app);
        end
        
        %%% display a page in result table
        %%% page: page # to display
        function goToPage(app,page)
            app.Session.ResultCurrentPage = page;
            updateResultPagination(app);
        end
        
        
        %%%%%%%%%%%%%%%%%%% original code     
        
        % populate the feature selection listbox with the selected feature
        function populateCompListBox(app)
            % get all the values from the selected row
            row = app.Session.InputData(app.Session.ComparisonFeatureType, 2:end);
            
            values = unique(row);
            [~, numVals] = size(values);
            if numVals == 0; return; end

            row=row';
            
            % empty string array to store comparison values
            comparisons = [];

            %for i = 1:length(values)
                %disp(values(i));
                %count = sum(find(strcmp(values(i),string(row))));
                %disp(count);
            %end
            
            % loop through values to get every combination of comparisons
            if strcmp(app.Session.ComparisonDropDownChoice,'Show all comparisons')
%                 counter = 2
                cats = categorical(row);
                uniqueCats = unique(cats);
                numEachCat = countcats((cats));
                
                catNames = cellstr(uniqueCats);
                numEachCat = cellfun(@(x) strtrim(num2str(x)),num2cell(numEachCat),'UniformOutput',false); 
                catNames = strcat(catNames,{' ('},numEachCat,')');
                allCombs = nchoosek(catNames,2);
                
                compNames = strcat(allCombs(:,1),{' vs '},allCombs(:,2));
                
                comparisons = string(compNames); 
%                 for i = 1:numVals-1
%                     for j = counter:numVals
%                         % create comparison string
%                         
%                         count1 = 0;
%                         for k = 1:length(row)
%                             if strcmp(row(k),{values{i}})
%                                 count1 = count1 + 1;
%                             end
%                         end
%                         count2 = 0;
%                         for k = 1:length(row)
%                             if strcmp(row(k),{values{j}})
%                                 count2 = count2 + 1;
%                             end
%                         end
%                         
%                         newstr = sprintf("%s (%s) vs %s (%s)", ...
%                             string(values{i}), ...
%                             string(count1), ...
%                             string(values{j}), ...
%                             string(count2) ...
%                         );
%                         comparisons = [comparisons newstr];
%                     end
%                     counter = counter + 1;
%                 end
            else                
%                 counter = 1;
%                 for i = 1:numVals
%                     if any(strcmp(string(values),app.Session.ComparisonDropDownChoice))

                        cats = categorical(row);
                        uniqueCats = unique(cats);
%                         targetGrp = uniqueCats(uniqueCats == app.Session.ComparisonDropDownChoice);
                        numEachCat = countcats((cats));

                        catNames = cellstr(uniqueCats);
                        numEachCat = cellfun(@(x) strtrim(num2str(x)),num2cell(numEachCat),'UniformOutput',false);
                        catNames = strcat(catNames,{'('},numEachCat,')');
                    
                        allCombs = nchoosek(catNames,2);
                        ind = any(strcmp(allCombs,catNames(uniqueCats == app.Session.ComparisonDropDownChoice)),2);
                        
                        compNames = strcat(allCombs(:,1),{' vs '},allCombs(:,2));

                        compNamesStr = string(compNames);
                        comparisons = compNamesStr(ind);
%                         for j = counter:numVals
%                             % create comparison string                            
%                             
%                             if ~strcmp(string(values{j}),app.Session.ComparisonDropDownChoice)
%                                 count1 = 0;
%                                 for k = 1:length(row)
%                                     if strcmp(row(k),{values{i}})
%                                         count1 = count1 + 1;
%                                     end
%                                 end
%                                 count2 = 0;
%                                 for k = 1:length(row)
%                                     if strcmp(row(k),{values{j}})
%                                         count2 = count2 + 1;
%                                     end
%                                 end
%                                 
%                                 newstr = sprintf("%s (%s) vs %s (%s)", ...
%                                     string(values{i}), ...
%                                     string(count1), ...
%                                     string(values{j}), ...
%                                     string(count2) ...
%                                 );
%                                 comparisons = [comparisons newstr];
%                             end
%                         end
%                         break
%                     end 
%                 end
            end
            % update dropdown
            app.ShowComparisonsDropDown.Items = ['Show all comparisons',values];
            app.ShowComparisonsDropDown.Value = app.Session.ComparisonDropDownChoice;
            % update list box
            app.ComparisonsListBox.Items = comparisons;
            app.ComparisonsListBox.Value = app.ComparisonsListBox.Items(1);
          
            ComparisonsListBoxButtonValueChanged(app,0);
        end      
        
    end
        
    methods (Access = public)
        %%% public functions that can be accessed by other mlapps
        
        %%% refresh the plot
        %%% used by plot style settings to update the plot with new styles
        function refreshPlot(app) 
            % clean plot
            backup = app.Session.ResultPlottedFeatures;
            app.Session.ResultPlottedFeatures = [];
            cleanPlot(app);            
            % re-add everything to plot
            for i = 1:length(backup)
                addToPlot(app,backup(i));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Figure.Icon = 'logo.png';
            
            % IMPORTANT initialize session data, explicitly decalre them
            
            app.Session.SessionName = 'NewSession';
            app.Session.SingleFile = 0;
            app.Session.FileName = '';
            app.Session.FilePath = '';
            app.Session.LastSavedDate = date;
            
            app.Session.InputFileName = '';
            app.Session.InputDataPath = '';
            app.Session.InputData = {};
            app.Session.InputDataLogTransformed = 0;
            app.Session.InputDataCheck = 'Unknown';
            app.Session.InputDataCheckColor = '#e0770d';
            app.Session.InputDataShowFullTable = 0;
            app.Session.InputDataImported = 0;
            app.Session.NumberOfComparisonTypes = '-';
            app.Session.NumberOfGenes = '-';
            app.Session.NumberOfSamples = '-';
            
            app.Session.ComparisonFeatureType = 0;
            app.Session.ComparisonDropDownChoice = 'Show all comparisons';
            app.Session.Comparison1 = '';
            app.Session.Comparison2 = '';
            app.Session.ClusteringComparison1 = '';
            app.Session.ClusteringComparison2 = '';
            
            app.Session.FeatureSelectionAlgorithmsToRun = strings(0);
            app.Session.FeatureSelectionCrossValidationType = 'K-fold';
            app.Session.FeatureSelectionKfoldK = 5;
            app.Session.FeatureSelectionParallelComputing = false;
            app.Session.FeatureSelectionIterations = '5';
            
            app.Session.ResultRankedCellData = {};
            app.Session.ResultRankedFeatures = {};
            app.Session.ResultViewing ='Ranked features';
            app.Session.ResultExportPath = '';
            app.Session.ResultsFeaturesToClusterWay = 'custom';
            app.Session.ResultsFeaturesToClusterPercentage = '5';
            app.Session.ResultSelectedIndices = [];
            app.Session.ResultSelectedFeatures = [];
            app.Session.ResultSelectedFeaturesData = [];
            app.Session.ResultPlottedFeatures = [];
            app.Session.ResultPlotLogTransformed = 0;
            app.Session.ResultComparison1Color = [0 0.4 0.69];
            app.Session.ResultComparison2Color = [0.95 0.66 0.21];
            app.Session.ResultComparison1MarkerType = '.';
            app.Session.ResultComparison2MarkerType = '*';
            app.Session.ResultComparison1LineWidth = 1.5;
            app.Session.ResultComparison2LineWidth = 1.5;
            app.Session.ResultComparison1Size = 15;
            app.Session.ResultComparison2Size = 10;
            app.Session.ResultLargeTables = 0;
            app.Session.ResultRowsPerPage = 200;
            app.Session.ResultCurrentPage = 1;
            app.Session.ResultTotalPages = 1;
            
            app.Session.ClusteringSamplesSimilarity = 'spearman';
            app.Session.ClusteringFeaturesSimilarity = 'euclidean';
            app.Session.ClusteringLinkage = 'average';
            app.Session.ClusteringColourMap = 'myColourMap_RedBlue';
            app.Session.ClusteringDisplayRange = 3;
            app.Session.ClusteringComparison1 = '';
            app.Session.ClusteringComparison2 = '';
            
            xlabel(app.ResultsUIAxes,'Select a feature','Color','#9C9C9C');     
            ylabel(app.ResultsUIAxes,'Select a feature','Color','#9C9C9C'); 
            app.CurrentVersion.Text = app.Version;        
            
            % check for update
            try
                % update config file link
                update = jsondecode(webread(app.CheckForUpdateLink));
                % json data: newest_version, critical, notes, link
                if ~strcmp(update.newest_version,app.Version)
                    app.UpdateButton.Text = update.newest_version + " Available";
                    app.UpdatenotesLabel.Text = update.notes;
                    app.UpdatenotesLabel.Visible = 'on';
                    app.VersionLink = update.link;
                    if update.critical == 1
                        uialert(app.Figure, "A critical update is available. " + update.notes + " Learn more in About tab.", "Critical Update", "Icon", "info");  
                    end
                else 
                    app.UpdateButton.Text = "Up-to-date";
                end
            catch e
                app.UpdateButton.Text = "Failed to Check";
            end
                        
            all_toolboxes = struct2cell(ver);
            all_toolboxes = all_toolboxes(1,:);
            app.HasBioInfoToolbox =  any(contains(all_toolboxes,'Bioinformatics'));
            app.HasDeepLearningToolbox = any(contains(all_toolboxes,'Deep Learning'));
            app.HasParallelComputingToolbox = any(contains(all_toolboxes,'Parallel Computing'));
            app.HasStatToolbox = any(contains(all_toolboxes,'Statistics and Machine Learning'));
            updateAboutTab(app);
        end

        % Button pushed function: ImportDataNextButton
        function ImportDataNextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ComparisonsSelectionTab;
            app.TabGroupSelectionChanged(app);
        end

        % Button pushed function: DataSelectionBackButton
        function DataSelectionBackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputDataTab;
        end

        % Button pushed function: ResultsBackButton
        function ResultsBackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FeatureSelectionTab;
        end

        % Button pushed function: FeatureSelectionBackButton
        function FeatureSelectionBackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ComparisonsSelectionTab;
        end

        % Button pushed function: DataSelectionNextButton
        function DataSelectionNextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FeatureSelectionTab;
            app.TabGroupSelectionChanged(app);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            %%% import data
            
            % ppen file selector
            [filename, path] = uigetfile([app.Session.InputDataPath '*.xlsx;*.xls;*.csv']);
            
            % do not proceed if user pressed cancel
            if filename ~= 0
                try
                    % create backup
                    backup = app.Session;
                     
                    % show progress dialog
                    waitbar = uiprogressdlg(app.Figure,'Title','Importing...' + string(filename) + newline,'Indeterminate','on');
                    drawnow
                    if (dir(string(path) + string(filename)).bytes > 10 * 10^6); waitbar.Message = 'The file is large. It may take a minute to import.'; end
                    app.Session.InputData = excelRead(path, filename); % reads file
                                
                    app.Session.InputFileName = filename;
                    app.Session.InputDataPath = path;
                    
                    % get number of rows of data from file
                    [rows, cols] = size(app.Session.InputData);
        
                    % load app.ImportDataTable with data from the excel file 
                    waitbar.Message = string(waitbar.Message) + 'Loading table to the preview area...' + newline;
                    set(app.ImportedDataTable, 'Data', snapshotTable(app,app.Session.InputData), 'ColumnEditable', [true, false])          
                    app.ImportedDataTable.ColumnName = 'numbered';
                    app.ImportedDataTable.RowName = 'numbered';
                    
                    % find all rows that are headers
                    waitbar.Message = string(waitbar.Message) + 'Finding labels...' + newline;
                    for row = 2:rows
                        % if the value in the second column of the current row is a
                        % numeric data value, then we have found all header rows
                        if isnumeric(app.Session.InputData{row,2}) == 1
                            app.Session.NumberOfComparisonTypes = row - 2;
                            break
                        end
                    end
                    app.Session.NumberOfGenes = rows - app.Session.NumberOfComparisonTypes - 1;
                    app.Session.NumberOfSamples = cols - 1;
                    
                    % if any labels are logical, make them char
                    for i = 2:app.Session.NumberOfComparisonTypes + 1
                        for j = 2:cols
                            ij = cell2mat(app.Session.InputData(i,j));
                            if(islogical(ij))
                                if ij == 1
                                   app.Session.InputData(i,j) = {['true']}; 
                                else 
                                    app.Session.InputData(i,j) = {['false']}; 
                                end
                            end
                        end
                    end
                    
                    % update input data tab
                    pass = valueCheck(app,app.Session.InputData);
                    if pass == 1
                        app.Session.InputDataCheckColor = '#63A238';       
                        app.Session.InputDataCheck = "No negative values";
                    else
                        app.Session.InputDataCheckColor = '#A2142F';
                        app.Session.InputDataCheck = "Negative value found";
                    end
                    updateInputDataTab(app);
                    
                    % update input data tab
%                     waitbar.Message = string(waitbar.Message) + 'Updating data input tab...' + newline;
                    updateInputDataTab(app);
                    
                    % finish data importing
                    app.Session.InputDataImported = 1;                    
                    
                    % close the waitbar and bring window to front
%                     close(waitbar);
                    figure(app.Figure)
                    
                    % Check if the first column is a list of gene names

%                     if strcmp(selection, 'Yes')
                        areAllStr = ~(cellfun(@(x) isstring(x) || ischar(x), app.Session.InputData(end - app.Session.NumberOfGenes+1:end,1)));
                        if all(areAllStr)
                            selection = uiconfirm(app.Figure, ...
                                'Failed to find list of feature ids. Is the first column a list of feature ids? ',...
                                'Feature Names','Options',{'Yes','No'},'DefaultOption','Yes');

                            if strcmp(selection,'Yes')
                                if ~isempty(find(areAllStr, 1))
                                    for i = 1:length(areAllStr)
                                        app.Session.InputData(areAllStr(i),1) = {num2str(app.Session.InputData{areAllStr(i),1})};
                                    end
                                end
                            else % if strcmp(selection, 'No')
                                warndlg ('First column of input data must contain feature ids.')
                                firstRowLabel = {'Sample IDs'};
                                comparisonLabels = num2cell(1:app.Session.NumberOfComparisonTypes)'; comparisonLabels = cellfun(@(x) num2str(x),comparisonLabels,'UniformOutput',0);
                                comparisonLabels = cellfun(@(x) ['Comparison_' x], comparisonLabels,'UniformOutput', false);
                                featureLabels = num2cell(1:app.Session.NumberOfGenes)'; featureLabels = cellfun(@(x) num2str(x),featureLabels,'UniformOutput',0);
                                featureLabels = cellfun(@(x) ['Feature_' x], featureLabels,'UniformOutput', false);
                                appendFeatureNames = [firstRowLabel;comparisonLabels;featureLabels];
                                app.Session.InputData = [appendFeatureNames app.Session.InputData];

                                updateInputDataTab(app);
                            end
                        end

                    % Remove any spaces or math chars from labels
                    app.Session.InputData(1:app.Session.NumberOfComparisonTypes,:) = regexprep(app.Session.InputData(1:app.Session.NumberOfComparisonTypes,:),'\W','_');
                    updateInputDataTab(app);
                    
                    % update feature selection tab
%                     waitbar.Message = string(waitbar.Message) + 'Updating comparisons tab...' + newline;
                    cleanComparisonsSelectionTabFeatureTypeBox(app); 
                    updateComparisonsSelectionTab(app);
                    
                    % close the waitbar and bring window to front
%                     close(waitbar);
%                     figure(app.Figure)
                    % ask if log transformed
                    selection = uiconfirm(app.Figure, ...
                        'Is the input data already log transformed? If yes, MolecularFeaST will disable log transformation features.',...
                        'Log Transformation',...
                        'Options',{'Yes','No/Unsure'},'DefaultOption',2);
                    if strcmp(selection, 'Yes')
                        app.Session.InputDataLogTransformed = 1;
                        app.LogTransformationButton.Enable = 'off';
                    else % if strcmp(selection, 'No/Unsure')
                        app.Session.InputDataLogTransformed = 0;
                        app.LogTransformationButton.Enable = 'on';
                    end
                    
                 catch e
                     app.Session = backup;
                     uialert(app.Figure, "An error was caught when importing the data: " + e.message, "Warning", "Icon", "warning");
                 end
            end
        end

        % Value changed function: AllCheckBox, FictBagCheckBox, 
        % ...and 13 other components
        function AlgorithmsPanelSelectionChanged(app, event)
            %%% update the algorithms selected 
            
            allAlgorithms = allchild(app.AlgorithmsCheckboxes);
            allAlgorithms = allAlgorithms(allAlgorithms ~= app.AllCheckBox);
            if strcmp(event.Source.Tag, 'All')
                for i = 1:size(allAlgorithms) % apply to all
                    allAlgorithms(i).Value = event.Source.Value;
                end
                app.Session.FeatureSelectionAlgorithmsToRun = strings(0); % uncheck all
                if event.Source.Value == 1 % check all
                    for i = 1:size(allAlgorithms)
                        app.Session.FeatureSelectionAlgorithmsToRun = ...
                            [app.Session.FeatureSelectionAlgorithmsToRun, string(allAlgorithms(i).Tag)];
                    end   
                end
            else
                if event.Source.Value == 0
                    app.Session.FeatureSelectionAlgorithmsToRun = ...
                        setdiff(app.Session.FeatureSelectionAlgorithmsToRun, string(event.Source.Tag));
                else
                    try
                        app.Session.FeatureSelectionAlgorithmsToRun = ...
                        [app.Session.FeatureSelectionAlgorithmsToRun, string(event.Source.Tag)];
                    catch % if some dimensional error occurs
                        disp('algorithm selection error catched');
                        app.Session.FeatureSelectionAlgorithmsToRun = strings(0); % uncheck all
                        for i = 1:size(allAlgorithms) % re-add all algorithms
                            if allAlgorithms(i).Value == 1
                                app.Session.FeatureSelectionAlgorithmsToRun = ...
                                    [app.Session.FeatureSelectionAlgorithmsToRun, string(allAlgorithms(i).Tag)];
                            end
                        end
                    end   
                end
            end
            % update AllCheckBox
            if length(app.Session.FeatureSelectionAlgorithmsToRun) == length(allAlgorithms)
                app.AllCheckBox.Value = 1;
            else
                app.AllCheckBox.Value = 0;
            end
            
            % update component availability
            updateFeatureSelectionTabHelper(app);

            % update summary
            updateSummary(app);
        end

        % Value changed function: ComparisonsListBox, SummaryTextArea
        function ComparisonsListBoxButtonValueChanged(app, event)
            %%% comparison selection updated, get the comparisons
            
            splitFeatures = split(app.ComparisonsListBox.Value, ' vs ');
            
%             [~,s1] = regexp(splitFeatures{1},'[\s]\([^)]*\)|(\s*,\s*)','match','split');
            s1 = split(splitFeatures(1),'('); s1 = s1{1};
                        s2 = split(splitFeatures(2),'('); s2 = s2{1};

%             [~,s2] = regexp(splitFeatures{2},'[\s]\([^)]*\)|(\s*,\s*)','match','split');
            app.Session.Comparison1 = strtrim(s1);
            app.Session.Comparison2 = strtrim(s2);
            updateSummary(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %%% update tab 
            
            selectedTab = app.TabGroup.SelectedTab;
            % if needs to perform certain actions when a tab is selected:
            if selectedTab == app.InputDataTab                
            elseif selectedTab == app.ComparisonsSelectionTab
%             elseif selectedTab == app.ComparisonsSelectionTab
            elseif selectedTab == app.FeatureSelectionTab
            elseif selectedTab == app.ResultsTab                
            elseif selectedTab == app.ClusteringTab                
            end                        
            app.currentTab = app.TabGroup.SelectedTab;  
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           %%% start feature selection algorithms
           
            % if no data input or algorithm selected, don't run 
            if ~app.Session.InputDataImported
                uialert(app.Figure, "Please import input data first.", "Warning", "Icon", "warning");
                return
            elseif strcmp(app.Session.Comparison1, '') || strcmp(app.Session.Comparison2, '')                
                uialert(app.Figure, "Please choose a pair of features to compare.", "Warning", "Icon", "warning");
                return
            elseif isempty(app.Session.FeatureSelectionAlgorithmsToRun)
                uialert(app.Figure, "Please select at least one algorithm.", "Warning", "Icon", "warning");
                return
            end
        
            % retrieve parameters to run feature selection
            validationType = app.Session.FeatureSelectionCrossValidationType;
            useParallel = app.Session.FeatureSelectionParallelComputing;
            k = app.Session.FeatureSelectionKfoldK;
            
            % TODO update this
            IS_max_iterations = str2double(app.Session.FeatureSelectionIterations);
            
            % get all compared samples from the data 
            temp = app.Session.InputData(app.Session.ComparisonFeatureType, :);
            samples2select = (strcmpi(temp, app.Session.Comparison1)) | (strcmpi(temp, app.Session.Comparison2));
            % re-add mRNA names (evaluated to 0 previously)
            samples2select(1) = 1;
            % trim the columns to what to compare
            data2use = app.Session.InputData(:, samples2select);
            
            % format classLabels parameter for runFeatureSelect_v3
            classLabels = data2use(app.Session.ComparisonFeatureType, 2:end)';
            app.Session.ClassLabel = classLabels; % sync to session   
            data2use = ...
                [data2use(app.Session.ComparisonFeatureType, :); ... % selected category
                data2use(app.Session.NumberOfComparisonTypes + 2:end, :)]; % selected data
            
            selectedAlgs = app.Session.FeatureSelectionAlgorithmsToRun;
            
            % If k value is too large
             size_groups = accumarray(grp2idx(classLabels(:)),1);
            if strcmp(validationType,'K-fold') && any(size_groups < k & size_groups > 0)
                uiwait(warndlg('Warning: One or more of the unique class values in GROUP is not present in one or more folds.'));
            end
            % display wait bar    
            waitbar = uiprogressdlg(app.Figure,'Title','Please wait...','Indeterminate','on');
            drawnow
            
            try                
                waitbar.Message = 'Running feature selection algorithms...';
                tic;
                % run the algorithm
                % runFeatureSelect_v3(algValues, [headings, data2use], indexRow', crossVal, kval, useParallel);
                [app.Session.ResultRankedFeatures, app.Session.ResultRankedCellData] = ...
                    runFeatureSelect_v3(data2use, classLabels, validationType, k, useParallel, IS_max_iterations, selectedAlgs, app.Session.InputDataLogTransformed);                             
                waitbar.Message = string(waitbar.Message) + toc + " seconds" + newline + 'Updating result and clustering tabs...';
                
                % update result and clustering tab
                app.Session.ResultPlottedFeatures = [];
                app.Session.ResultSelectedFeatures = [];
                app.Session.ResultSelectedFeaturesData = [];
                updateResultTab(app);
                FeaturesToClusterWaysButtonGroupSelectionChanged(app, 0);
                updateClusteringTab(app);
                waitbar.Message = string(waitbar.Message) + toc + " seconds" + newline;
                pause(3);
            
                % close dialog box
                close(waitbar);
                % select results tab
                app.TabGroup.SelectedTab = app.ResultsTab;
                
            catch e
                % close waitbar if error catched
                fprintf(1,'The identifier was:\n%s\n',e.identifier);
                fprintf(1,'There was an error! The message was:\n%s\n',e.message);
                close(waitbar);
                % show alert dialog
                uialert(app.Figure, e.message, "Computation stopped. An error is catched.", "Icon", "warning");
                return
            end
        close(waitbar);
        end

        % Button pushed function: ShowSampleDataButton
        function ShowSampleDataButtonPushed(app, event)
            sampleData = imread("SampleDataExample.jpg");
            imshow(sampleData);
        end

        % Button pushed function: ExportResultsButton
        function ExportDataButtonPushed(app, event)
            %%% export the results to an excel or mat file
            
            % skip if no result are there yet
            if isempty(app.Session.ResultRankedFeatures) || isempty(app.Session.ResultRankedCellData); return; end
            
            % check user choice
            selection = uiconfirm(app.Figure, ...
                'Which format to export the results to?', ...
                'Export Results','Options', ...
                {'Excel (.xlsx)','MATLAB (.mat)','Cancel'}, ...
                'DefaultOption',1,'CancelOption',3);
            
            % return if user cancelled
            if strcmp(selection,'Cancel'); return; end
            
            % gather ranked features
            resultRankedFeatures = app.Session.ResultRankedFeatures;
            
            % gather ranked cells and prepend the sample names
            % get all compared samples from the data 
            filter = app.Session.InputData(app.Session.ComparisonFeatureType, :);
            samples2select = (strcmpi(filter, app.Session.Comparison1)) | (strcmpi(filter, app.Session.Comparison2));
            % keep the column/cell at the beginning
            samples2select(1) = 1;
            % select by the sample names
            data2prepend = app.Session.InputData(1:app.Session.NumberOfComparisonTypes + 1, samples2select);
            % prepend them to the result, take away the default comparison type row first
            resultRankedCellData = [data2prepend;app.Session.ResultRankedCellData(2:end,:)];
            
            % show waitbar
            waitbar = uiprogressdlg(app.Figure,'Title','Please wait...','Indeterminate','on');
            
            % save data
            try
                if strcmp(selection,'Excel (.xlsx)')
                    waitbar.Message = 'Please choose the location to save the exported Excel sheets.';
                    app.Session.ResultExportPath = exportDataTo({resultRankedCellData,resultRankedFeatures},{'RankedCellData','RankedFeatures'},app.Session.ResultExportPath);
                    waitbar.Message = 'Exporting ranked cell data and features...';
                    close(waitbar);
                else % if strcmp(selection,'MATLAB (.mat)')
                    waitbar.Message = 'Please choose the location to save the exported MATLAB file.';                    
                    [file,path] = uiputfile([app.Session.ResultExportPath '*.mat'],'Export Results');
                    if class(file) == "double" || class(path) == "double"; close(waitbar); return; end
                    app.Session.ResultExportPath = path;
                    save(string(path) + string(file),'resultRankedCellData','resultRankedFeatures');
                    waitbar.Message = 'Exporting ranked cell data and features...';
                    close(waitbar);
                end
            catch e
                close(waitbar);
                uialert(app.Figure, e.message, "Exporting stopped. An error is catched.", "Icon", "warning");
            end
            
        end

        % Button pushed function: GenerateClustergram
        function GenerateClustergramPushed(app, event)
            %%% generate clustergram 
            
            % return if no feature selected
            if isempty(app.Session.ResultSelectedFeatures)
                uialert(app.Figure, "Please select the features to plot in Results tab first.", "Select Features", "Icon", "warning");
                return
            end

            
            % gather settings
            rowDist = app.FeaturessimilarityDropDown.Value;
            colDist = app.SamplessimilarityDropDown.Value;
            colorMap = app.ColourmapDropDown.Value;
            linkage = app.LinkageDropDown.Value;
            displayRange = app.DisplayrangeSpinner.Value;
            symmetric = app.SymmetricDropDown.Value;
            resultsPercent = 100; % no longer needs to specify
            
            [row,col] = size(app.Session.ResultSelectedFeatures);  
            rowLabels = reshape(app.Session.ResultSelectedFeatures,[col,row]);  
            
            datain = [];            
            rows = cell2mat(app.Session.ResultSelectedFeaturesData);
            for i = 1:length(rows)
                datain = [datain; app.Session.ResultRankedCellData(rows(i), 2:end)];
            end
            datain = cell2mat(datain);   

            if app.Session.InputDataLogTransformed == 1
                datain = 2.^datain; 
            end
            
            if any(datain < 0)
                % make sure there are no negative values
                datain = datain - min(min(datain));
            end

            % Spearman correlation does not work with less than three
            % features
%             if strcmp(colDist,'spearman') && length(app.Session.ResultSelectedFeatures) < 3 && ~isempty(app.Session.ResultSelectedFeatures)
%                 uialert(app.Figure, "Spearman  correlation is not possible with only two features.", "Select more than two features", "Icon", "info");
%                 return
%             end
            % generate clustergram
            try
                lastwarn('');
                lasterror('reset');
                clusterExpressionData(datain, resultsPercent, ...
                'RowLabels', rowLabels,...
                'ColumnLabels', app.Session.ClassLabel, 'RowPDist', rowDist,...
                'ColumnPDist', colDist, 'Colormap', colorMap, ...
                'Linkage', linkage, 'DisplayRange', displayRange,'Symmetric',symmetric);
                
                % Retrieve warning messages from clustegram operation and
                % treat as errors -> catch block
                [warnMsg,warnId] = lastwarn;
                if ~isempty(warnMsg)
                    warning('error',warnId);
                end

            catch
                % closing the window also gives an error -> commenting this line out
                err = lasterror;                
                switch err.identifier
                    case 'bioinfo:clustergram:clustergram:clusteringFailure'
                        message = sprintf('Too few features selected for plotting. Either select more features or choose another distance metric.');
                    otherwise
                        message = sprintf('An error is caught when clustering:\n%s', lasterr);
                end
%                 uiwait(warndlg(message));
                uialert(app.Figure, message, "Select Features", "Icon", "warning");

%                 warndlg(app.Figure, "An error is caught when clustering." + e.message, "Warning", "Icon", "warning");
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %%% update the selected tab
            
            app.TabGroup.SelectedTab = app.ClusteringTab;
        end

        % Value changed function: CategoricalLabelsListBox
        function CategoricalLabelsListBoxValueChanged(app, event)
            %%% update listbox            
            
            % clear the list box
            set(app.ComparisonsListBox, "Items", "", "Value", "");
            
            % get selected feature type
            app.Session.ComparisonFeatureType = app.CategoricalLabelsListBox.Value;
            app.Session.ComparisonDropDownChoice = 'Show all comparisons';
            
            % TODO replace this
            populateCompListBox(app);
        end

        % Key press function: Figure
        function UIFigureKeyPress(app, event)
            % allow user to use keyboard short cuts
            % Windows: 'control'
            % MacOS: 'command'
            try
                modifierKey = event.Modifier{:};
                % sample shortcut and message
                if (strcmp(modifierKey,'control') || strcmp(modifierKey,'command'))...
                        && strcmp(event.Key,'s')
                    app.SessionDropDown.Value = 'Save';   
                    SessionManagerValueChanged(app, 0); 
                end
            catch % catch the error if no modifier key is pressed
            end
        end

        % Value changed function: DisplayfulltableCheckBox
        function DisplayFullTableChanged(app, event)
            %%% update table
            
            value = app.DisplayfulltableCheckBox.Value;
            app.Session.InputDataShowFullTable = value;
            set(app.ImportedDataTable, 'Data', snapshotTable(app,app.Session.InputData), 'ColumnEditable', [true, false])
        end

        % Value changed function: ViewResultsDropDown
        function ViewResultsDropDownValueChanged(app, event)
            %%% update view result options
             
            value = app.ViewResultsDropDown.Value;
            app.Session.ResultViewing = value;
            app.Session.ResultCurrentPage = 1;
            updateResultPagination(app);
        end

        % Value changed function: ShowComparisonsDropDown
        function ShowComparisonsDropDownValueChanged(app, event)
            %%% update comparisons
            
            app.Session.ComparisonDropDownChoice = app.ShowComparisonsDropDown.Value;
            populateCompListBox(app);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            %%% callback when plot settings is deleted
            
            delete(app.PlotSettings); % delete plot settings
            delete(app) % delete main app 
        end

        % Value changed function: SessionDropDown
        function SessionManagerValueChanged(app, event)
            %%% session manager
            
            % get value
            value = app.SessionDropDown.Value;            
            
            if contains(value, 'Save')
                % if save as or saving for the first time:
                if strcmp(value, 'Save as') || strcmp(app.Session.FileName,'')
                    % display waitbar
                    app.SessionDropDown.Enable = 'off';  
                    waitbar = uiprogressdlg(app.Figure,'Title','Save Current Session','Indeterminate','on');
                    waitbar.Message = 'Please choose the location to save the current session.';     
                    % try to save the session
                    try
                        % ask for path
                        [file,path] = uiputfile([app.Session.InputDataPath '*.mat'],'Save Current Session');
                        if class(file) == "double" || class(path) == "double"
                           app.SessionDropDown.Value = app.SessionDropDown.Items(1);
                           app.SessionDropDown.Enable = 'on';
                           return
                        end
                        file = string(file);
                        path = string(path);                    
                        app.Session.FileName = file;
                        app.Session.FilePath = path;
                        % save session
                        app.Session.SessionName = convertStringsToChars(app.Session.FileName.erase('.mat'));
                        toSave = app.Session;
                        waitbar.Message = 'Saving current session...';
                        save(path + file,'-struct','toSave');
                        app.Session.LastSavedDate = date;
                    catch e
                        app.SessionDropDown.Enable = 'on';  
                        app.SessionDropDown.Value = app.SessionDropDown.Items(1);
                        close(waitbar)
                        uialert(app.Figure, "Error occured when saving the session.", "Warning", "Icon", "warning");
                        disp(e.message);
                        return
                    end   
                    % update session dropdown
                    app.SessionDropDown.Enable = 'on';  
                    app.SessionDropDown.Items = {app.Session.SessionName, 'Save', 'Save as', 'Open', 'Info'};
                    app.SessionDropDown.Value = app.Session.SessionName;
                    
                    close(waitbar)
                    uialert(app.Figure, "The current session is successfuly saved", "Session Saved", "Icon", "success");                  
                % if save:
                else
                    % display waitbar
                    waitbar = uiprogressdlg(app.Figure,'Title','Save Current Session','Indeterminate','on');
                    waitbar.Message = 'Saving current session...';
                    
                    % save session
                    toSave = app.Session;
                    save(app.Session.FilePath + app.Session.FileName,'-struct','toSave');
                    app.Session.LastSavedDate = date;
                    
                    close(waitbar)                     
                    figure(app.Figure);
                    uialert(app.Figure, "Saved to " + app.Session.FileName + " under " + app.Session.FilePath + ".", "Session Saved", "Icon", "success");
                    
                    % update session dropdown
                    app.SessionLabel.Text = "Last Saved " + string(app.Session.LastSavedDate);
                    app.SessionDropDown.Value = app.Session.SessionName;   
                end
            % if open:
            elseif strcmp(value, 'Open') 
                % ask for path
                [file,path] = uigetfile('*.mat');       
                % return if cancelled
                if class(file) == "double" || class(path) == "double"
                   app.SessionDropDown.Value = app.SessionDropDown.Items(1);
                   return
                end
                file = string(file);
                path = string(path);
                waitbar = uiprogressdlg(app.Figure,'Title','Opening...','Indeterminate','on');
                drawnow
                if ~isequal(file,0) && ~isequal(file,'0')
                    % try to open the session
                    try                        
                        if (dir(string(path) + string(file)).bytes > 1000 * 10); waitbar.Message = 'The file is large. It may take a minute to import.'; end
                        % save a backup
                        backup = app.Session;
                        % load session
                        app.Session = load(path + file);
                        % update tabs
                        updateInputDataTab(app);
                        updateComparisonsSelectionTab(app);
                        
                        updateFeatureSelectionTab(app);
                        updateResultTab(app);
                        updateClusteringTab(app);                        
                        close(waitbar);
                        figure(app.Figure);
                        app.SessionDropDown.Items = {app.Session.SessionName, 'Save', 'Save as', 'Open', 'Info'};
                        app.SessionDropDown.Value = app.Session.SessionName;
                        uialert(app.Figure, "Session " + app.Session.SessionName + " is opened from " + app.Session.FileName + ".", "Session Opened", "Icon", "success");
                    catch e
                        app.SessionDropDown.Value = app.SessionDropDown.Items(1);
                        close(waitbar);
                        % recover from a backup
                        app.Session = backup;
                        % update tabs
                        updateInputDataTab(app);
                        updateComparisonsSelectionTab(app);
                        updateFeatureSelectionTab(app);
                        updateResultTab(app);
                        updateClusteringTab(app);
                        figure(app.Figure);
                        uialert(app.Figure, "Error occured when opening the session. Please restart the program." + e.message, "Warning", "Icon", "warning");
                        return
                    end
                else 
                    app.SessionDropDown.Value = app.SessionDropDown.Items(1);
                end
            end
        end

        % Selection changed function: CrossValidationButtonGroup
        function CrossValidationButtonValueChanged(app, event)
            % update cross validation 
            
            selectedButton = app.CrossValidationButtonGroup.SelectedObject;
            app.Session.FeatureSelectionCrossValidationType = selectedButton.Tag;
            updateSummary(app);
        end

        % Value changed function: KSpinner
        function KfoldKValueChanged(app, event)
            % update kfold value
            
            value = app.KSpinner.Value;
            app.Session.FeatureSelectionKfoldK = value;
            updateSummary(app);
        end

        % Selection changed function: ParallelComputingButtonGroup
        function ParallelComputingButtonValueChanged(app, event)
            % update parallel computing 
            
            selectedButton = app.ParallelComputingButtonGroup.SelectedObject;
            if strcmp(selectedButton.Tag,'on')
                app.Session.FeatureSelectionParallelComputing = true;
            else
                app.Session.FeatureSelectionParallelComputing = false;
            end
            updateSummary(app);
        end

        % Value changed function: IterationsDropDown
        function IterationsDropDownValueChanged(app, event)
            % update iterations
            
            value = app.IterationsDropDown.Value;
            app.Session.FeatureSelectionIterations = value;
            updateSummary(app);
        end

        % Value changed function: SamplessimilarityDropDown
        function SamplessimilarityDropDownValueChanged(app, event)
            % update sample similarity
            
            value = app.SamplessimilarityDropDown.Value;            
            app.Session.ClusteringSamplesSimilarity = value;
        end

        % Value changed function: FeaturessimilarityDropDown
        function FeaturessimilarityDropDownValueChanged(app, event)
            % update feature similarity
            
            value = app.FeaturessimilarityDropDown.Value;
            app.Session.ClusteringFeaturesSimilarity = value;
        end

        % Value changed function: LinkageDropDown
        function LinkageDropDownValueChanged(app, event)
            % update linkage 
            
            value = app.LinkageDropDown.Value;                
            app.Session.ClusteringLinkage = value;
        end

        % Value changed function: DisplayrangeSpinner
        function DisplayrangeSpinnerValueChanged(app, event)
            %%% update display range
            
            value = app.DisplayrangeSpinner.Value;
            app.Session.ClusteringDisplayRange = value;
        end

        % Value changed function: ColourmapDropDown
        function ColourmapDropDownValueChanged(app, event)
            %%% update colormap
            
            value = app.ColourmapDropDown.Value;            
            app.Session.ClusteringColourMap = value;
        end

        % Cell selection callback: ResultsTable
        function ResultsTableCellSelectionChanged(app, event)
            %%% update the plot when the selection on result table is changed
            
            indices = event.Indices;
            app.Session.ResultSelectedIndices = indices;
            app.AddCurrentFeatureButton.Enable = 'on';  
            % update plotting                 
            % plot selected features, if needed
            try    
                addToPlotViaPagination(app,app.Session.ResultSelectedIndices(1:1,1));
            catch e                
                uialert(app.Figure, "For some reason, plotting the selected feature failed: " + newline + e.message, "Warning", "Icon", "warning");
            end            
        end

        % Button pushed function: AddCurrentFeatureButton
        function AddCurrentFeatureButtonPushed(app, event)
            %%% add the current selected feature to the feature to cluster listbox
            
            % if manually adding -> custom feature selection mode
            if ~strcmp(app.Session.ResultsFeaturesToClusterWay,'custom')                
                app.CustomButton.Value = 1;
                % update to session
                app.Session.ResultsFeaturesToClusterWay = app.CustomButton.Tag;
            end            
            % append the current selected feature
            try          
                addToFeatureToClusterListboxViaPagination(app,app.Session.ResultSelectedIndices(:,1));      
            catch e                
                uialert(app.Figure, "For some reason, adding the selected feature failed: " + newline + e.message, "Warning", "Icon", "warning");
            end
        end

        % Button pushed function: RemoveCurrentFeatureButton
        function RemoveCurrentFeatureButtonPushed(app, event)
            %%% remove the currently selected features from the features to cluster listbox
            
            try   
                removeFromFeatureToClusterListbox(app,cell2mat(app.FeaturesToClusterListBox.Value));
            catch e                
                uialert(app.Figure, "For some reason, removing the selected feature failed: " + newline + e.message, "Warning", "Icon", "warning");
            end
        end

        % Value changed function: PercentageToClusterDropDown
        function PercentageToClusterDropDownValueChanged(app, event)
            %%% percentage to cluster changed
            %%% call
            %%% FeaturesToClusterWaysButtonGroupSelectionChanged(app,event) to update the listbox
            
            value = app.PercentageToClusterDropDown.Value;
            app.Session.ResultsFeaturesToClusterPercentage = value;
            app.PercentButton.Value = 1;
            FeaturesToClusterWaysButtonGroupSelectionChanged(app, 0);
        end

        % Selection changed function: FeaturesToClusterWaysButtonGroup
        function FeaturesToClusterWaysButtonGroupSelectionChanged(app, event)
            %%% update the feature to cluster listbox according to the clustering way selected
            
            % get result table size 
            [rows,~] = size(app.Session.ResultRankedCellData);
            % select all 
            if strcmp(app.FeaturesToClusterWaysButtonGroup.SelectedObject.Tag,'all')
                addToFeatureToClusterListbox(app,[2:rows]);
            % select top _ %
            elseif strcmp(app.FeaturesToClusterWaysButtonGroup.SelectedObject.Tag,'percentage')                
                clearFeatureToClusterListbox(app);
                tail = floor(str2double(app.Session.ResultsFeaturesToClusterPercentage) / 100 * (rows - 1));
                if tail == 0; tail = 1; end
                addToFeatureToClusterListbox(app,[2:1+tail]);
            % custom mode
            elseif strcmp(app.FeaturesToClusterWaysButtonGroup.SelectedObject.Tag,'custom')
                recolorResultTabTable(app); % recolor the results table
            end
            % sync with session
            app.Session.ResultsFeaturesToClusterWay = app.FeaturesToClusterWaysButtonGroup.SelectedObject.Tag;
        end

        % Button pushed function: RemoveAllFeatureButton
        function RemoveAllFeatureButtonPushed(app, event)
            %%% remove all features from the feature to cluster list
            
            % get user confirmation
            selection = uiconfirm(app.Figure, ...
                'Are you sure you want to remove all the features from the custom selection list?',...
                'Remove all of the selected features',...
                'Options',{'Remove','Cancel'},'DefaultOption',2);
            % user confirmed yes
            if strcmp(selection, 'Remove')
                % if manually removing, selection mode should be set to 'custom'
                if ~strcmp(app.Session.ResultsFeaturesToClusterWay,'custom')                
                    app.CustomButton.Value = 1;
                    app.Session.ResultsFeaturesToClusterWay = app.CustomButton.Tag;
                end
                % remove all features
                clearFeatureToClusterListbox(app);
            end
        end

        % Button pushed function: CopyCurrentFeatureButton
        function CopyCurrentFeatureButtonPushed(app, event)
            %%% pass the names of the selected feature to clipboard
            % e.g. "hsa-miR-106b(1), hsa-miR-106bSTAR(1), hsa-let-7f-1STAR(1), hsa-let-7g(1)"
            
            if ~isempty(app.FeaturesToClusterListBox.Items)
                clipboard('copy', erase(cell2mat(join(app.FeaturesToClusterListBox.Items, ', ')),"'"));
            else 
                clipboard('copy', 'No feature is selected yet');
            end
        end

        % Value changed function: LogTransformationButton
        function LogTransformationButtonPushed(app, event)
            %%% enable log transformation for plotted data
            
            value = app.LogTransformationButton.Value;
            app.Session.ResultPlotLogTransformed = value;
            refreshPlot(app);
        end

        % Button pushed function: RemoveXYFeatures
        function RemoveXYFeaturesButtonPushed(app, event)
            %%% remove the feature(s) plotted
            
            % clear plotted features list
            app.Session.ResultPlottedFeatures = [];
            % clear the plot       
            cleanPlot(app);
            for i = 1:length(app.Session.ResultPlottedFeatures)
                % clear styles
            end            
        end

        % Button pushed function: AddYFeatureToSelected
        function AddYFeatureToSelectedButtonPushed(app, event)
            %%% add the feature plotted on y axis to the selected features list
            
            % if manually adding -> custom feature selection mode
            if ~strcmp(app.Session.ResultsFeaturesToClusterWay,'custom')                
                app.CustomButton.Value = 1;
                app.Session.ResultsFeaturesToClusterWay = app.CustomButton.Tag;
            end            
            % add the feature
            try          
                addToFeatureToClusterListbox(app,[app.Session.ResultPlottedFeatures(1)]);                
            catch e                
                uialert(app.Figure, "Adding the selected feature failed because: " + newline + e.message, "Warning", "Icon", "warning");
            end
        end

        % Button pushed function: AddXFeatureToSelected
        function AddXFeatureToSelectedButtonPushed(app, event)
            %%% add the feature plotted on x aixs to the selected features list
            
            % if manually adding -> custom feature selection mode
            if ~strcmp(app.Session.ResultsFeaturesToClusterWay,'custom')                
                app.CustomButton.Value = 1;
                app.Session.ResultsFeaturesToClusterWay = app.CustomButton.Tag;
            end            
            % add the feature
            try          
                addToFeatureToClusterListbox(app,[app.Session.ResultPlottedFeatures(2)]);
            catch e                
                uialert(app.Figure, "Adding the selected feature failed because: " + newline + e.message, "Warning", "Icon", "warning");
            end
        end

        % Button pushed function: RemoveXFeatureFromSelected
        function RemoveXFeatureFromSelectedButtonPushed(app, event)
            %%% remove the features plotted on x axis from the selected features list
            
            removeFromFeatureToClusterListbox(app,[app.Session.ResultPlottedFeatures(2)]);
        end

        % Button pushed function: RemoveYFeatureFromSelected
        function RemoveYFeatureFromSelectedButtonPushed(app, event)
            %%% remove the features plotted on y axis from the selected features list
            
            removeFromFeatureToClusterListbox(app,[app.Session.ResultPlottedFeatures(1)]);
        end

        % Button pushed function: PlotNextTwo
        function PlotNextTwoButtonPushed(app, event)
            %%% plot next two feautres if possible
            
            [rows,~] = size(app.Session.ResultRankedCellData);
           
            % if 1 plotted, try add the next one
            if length(app.Session.ResultPlottedFeatures) == 1
                if app.Session.ResultPlottedFeatures(1) < rows-1
                    addToPlot(app,[app.Session.ResultPlottedFeatures(1)+1]);
                end
            % if 2 plotted, try move to the next 2
            elseif length(app.Session.ResultPlottedFeatures) == 2
                mi = min(app.Session.ResultPlottedFeatures);
                ma = max(app.Session.ResultPlottedFeatures);
                if mi+1 == ma
                    m = ma;
                else 
                    m = mi;
                end
                if m+2 < rows-1
                    addToPlot(app,[m+1]);
                    addToPlot(app,[m+2]);
                end
            % if none plotted, try plot the first 2
            elseif isempty(app.Session.ResultPlottedFeatures)
                if rows >= 3
                    addToPlot(app,[2]);
                    addToPlot(app,[3]);
                end
            end
        end

        % Button pushed function: PlotPreviousTwo
        function PlotPreviousTwoButtonPushed(app, event)
            %%% plot previous two feautres if possible
            
            % get result size
            [rows,~] = size(app.Session.ResultRankedCellData);
            % if 1 plotted, try add the previous one
            if length(app.Session.ResultPlottedFeatures) == 1
                if app.Session.ResultPlottedFeatures(1) > 2
                    addToPlot(app,[app.Session.ResultPlottedFeatures(1)-1]);
                end
            % if 2 plotted, try move to the previous 2
            elseif length(app.Session.ResultPlottedFeatures) == 2
                mi = min(app.Session.ResultPlottedFeatures);
                ma = max(app.Session.ResultPlottedFeatures);
                if mi+1 == ma
                    m = mi;
                else 
                    m = ma;
                end
                if m-2 > 1
                    addToPlot(app,[m-1]);
                    addToPlot(app,[m-2]);
                end
            % if none plotted, try plot the first 2
            elseif isempty(app.Session.ResultPlottedFeatures)
                if rows >= 3
                    addToPlot(app,[2]);
                    addToPlot(app,[3]);
                end
            end
        end

        % Value changed function: PlotStyleSettings
        function PlotStyleSettingsPlotStyleSettingsPushed(app, event)
            %%% give user settings for plot styles
            
            % select the button
            app.PlotStyleSettings.Value = 1;
            % call the settings figure
            if isempty(app.PlotSettings); app.PlotSettings = PlotSettings(app); end
        end

        % Button pushed function: NextResultPage
        function NextResultPageButtonPushed(app, event)
            %%% go to the nex page if the current apge is not the
            %%% last page
            
            if (app.Session.ResultCurrentPage < app.Session.ResultTotalPages)
                app.Session.ResultCurrentPage = app.Session.ResultCurrentPage + 1;
                updateResultPagination(app);
            end
        end

        % Button pushed function: LastResultPage
        function LastResultPageButtonPushed(app, event)
            %%% go to the previous page if the current apge is not the
            %%% first page
            
            if (app.Session.ResultCurrentPage > 1)
                app.Session.ResultCurrentPage = app.Session.ResultCurrentPage - 1;                
                updateResultPagination(app);
            end
        end

        % Button pushed function: Button
        function tsneButtonPushed(app, event)
            %%% make tsne plot from the features selected
            
            % get data
            selected = cell2mat(app.Session.ResultSelectedFeaturesData);
            if length(selected) < 2
                uialert(app.Figure, "t-SNE requires two or more features to be selected.", "Select Features", "Icon", "warning");
               return; 
            end
            data = cell2mat(app.Session.ResultRankedCellData(selected,2:end));
            data = tsne(data'); 
            
            % get labels
            labels = convertCharsToStrings(app.Session.ResultRankedCellData(1,2:end));
            labels = labels';
            % labels = convertCharsToStrings(app.Session.ResultRankedCellData(selected,1));
            
            % get marker types
            markers = [app.Session.ResultComparison1MarkerType app.Session.ResultComparison2MarkerType];
            
            % get marker colors
            colors = [app.Session.ResultComparison1Color;app.Session.ResultComparison2Color];
            
            % plot
            figure('Name','TSNE');
            gscatter(data(:,1),data(:,2),labels,colors,markers,[app.Session.ResultComparison1Size;app.Session.ResultComparison2Size]);
            legend('Location','northeastoutside');
        end

        % Value changed function: FeaturesToClusterListBox
        function FeaturesToClusterListBoxSelectionChanged(app, event)
            %%% when user select a feautre in selected feature listbox,
            %%% show it in the result table
            
            % get the current selection
            value = cell2mat(app.FeaturesToClusterListBox.Value);
            % calculate the page it is on result table
            page = ceil((value - 1) / app.Session.ResultRowsPerPage);
            % go to the page
            goToPage(app,page);
            % calcualte the row it is on the page 
            row = mod(value - 1,app.Session.ResultRowsPerPage);
            % select the row (if mod gives 0 then it's the last one on the page)
            if row == 0; row = app.Session.ResultRowsPerPage; end
            selectRow(app,row);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            try
                web(app.VersionLink, '-browser')
            catch e
            end
        end

        % Button pushed function: UserManualButton
        function UserManualButtonPushed(app, event)
            try
                web(app.ManualLink, '-browser')
            catch e
            end
        end

        % Value changed function: SymmetricDropDown
        function SymmetricDropDownValueChanged(app, event)
            value = app.SymmetricDropDown.Value;
            app.Session.Symmetric = value;
        end

        % Button pushed function: PredictiveImportancePlotButton
        function PredictiveImportancePlotButtonPushed(app, event)
               % skip if no result are there yet
            if isempty(app.Session.ResultRankedFeatures) || isempty(app.Session.ResultRankedCellData); return; end
            
            dat = app.Session.ResultRankedFeatures;

            % Average scores for the feature selection algorithms
            avgFS_scores = mean(cell2mat(dat(2:end,3:end-7)),2);

            % Plot average scores with labels for percentile ranks
%             fig = uifigure;
%             ax = uiaxes(fig);
%             bar(app.UIFigure,avgFS_scores);
            figure('Position', [50 50 950 650],'Name','Predictive Importance Plot'); hold on;
            bar(avgFS_scores);
            for i = [0.01 0.05:0.05:1]
                xline(ceil(length(avgFS_scores)*i),'LineWidth',1,'LineStyle','--','Label',sprintf('%.0f%%',i*100),'LabelHorizontalAlignment','center')
            end
            ylabel('Average Discriminatory Ability','FontWeight','bold')
            xlabel('Ranked Features','FontWeight','bold')
            hold off
        end

        % Button pushed function: BoxplotButton
        function BoxplotButtonPushed(app, event)
            % Create boxplot
            rows = cell2mat(app.Session.ResultSelectedFeaturesData);
            datain = app.Session.ResultRankedCellData(rows, 2:end);

            datain = cell2mat(datain);

            if app.Session.InputDataLogTransformed == 1
                datain = 2.^datain;
            end

            if any(datain < 0)
                % make sure there are no negative values
                datain = datain - min(min(datain));
            end

            t = log2(replaceZeros(datain, 'lowval')); %log transform
            t = t-median(median(t));%median center the data
            figure;
            boxplot(t,'Colors',[0.6 0.6 0.6],'BoxStyle','outline','Symbol','k','MedianStyle','target','Labels',app.Session.ResultRankedCellData(1, 2:end));
            ylabel('log_2 Median Centered Expression','FontWeight','bold')
            xlabel('Samples','FontWeight','bold')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MFeaST';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Figure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.MainGrid = uigridlayout(app.Figure);
            app.LogoGrid = uigridlayout(app.MainGrid);
            app.Logo = uiimage(app.LogoGrid);
            app.Logo.ImageSource = 'logo.png';
            app.MolecularFeaSTLabel = uilabel(app.LogoGrid);
            app.MolecularFeaSTLabel.Text = 'MolecularFeaST';
            app.SessionDropDownGrid = uigridlayout(app.MainGrid);
            app.SessionDropDownGrid.Tooltip = {'Save the current session or load from a saved session'};
            app.SessionLabel = uilabel(app.SessionDropDownGrid);
            app.SessionLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.SessionDropDownGrid);
            app.SessionDropDown.Items = {'- Unsaved -', 'Save', 'Open'};
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionManagerValueChanged, true);
            app.SessionDropDown.Value = '- Unsaved -';
            app.TabGroup = uitabgroup(app.MainGrid);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.InputDataTab = uitab(app.TabGroup);
            app.InputDataTab.Title = 'Input Data';
            app.InputDataTabGrid = uigridlayout(app.InputDataTab);
            app.ImportDataButton = uibutton(app.InputDataTabGrid, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Tooltip = {'Choose a file with the data to import'};
            app.ImportDataButton.Text = 'Import Data';
            app.ShowSampleDataButton = uibutton(app.InputDataTabGrid, 'push');
            app.ShowSampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSampleDataButtonPushed, true);
            app.ShowSampleDataButton.Tooltip = {'Learn the format requirements for input data'};
            app.ShowSampleDataButton.Text = 'Show Sample Data';
            app.DataInfoPanel = uipanel(app.InputDataTabGrid);
            app.DataInfoPanel.Title = 'Data Info';
            app.DataInfoPanelGridLayout = uigridlayout(app.DataInfoPanel);
            app.FilenameLabelPrompt = uilabel(app.DataInfoPanelGridLayout);
            app.FilenameLabelPrompt.Text = 'Filename';
            app.FilenameLabel = uilabel(app.DataInfoPanelGridLayout);
            app.FilenameLabel.Text = '-';
            app.NumberofFeatureTypesLabelPrompt = uilabel(app.DataInfoPanelGridLayout);
            app.NumberofFeatureTypesLabelPrompt.Text = '# of comparison types';
            app.NumberofFeatureTypesLabel = uilabel(app.DataInfoPanelGridLayout);
            app.NumberofFeatureTypesLabel.Text = '-';
            app.PathLabelPrompt = uilabel(app.DataInfoPanelGridLayout);
            app.PathLabelPrompt.Text = 'Path';
            app.PathLabel = uilabel(app.DataInfoPanelGridLayout);
            app.PathLabel.Text = '-';
            app.NumberOfGenesLabelPrompt = uilabel(app.DataInfoPanelGridLayout);
            app.NumberOfGenesLabelPrompt.Text = '# of features';
            app.NumberOfGenesLabel = uilabel(app.DataInfoPanelGridLayout);
            app.NumberOfGenesLabel.Text = '-';
            app.ImportValueCheckLabelPrompt = uilabel(app.DataInfoPanelGridLayout);
            app.ImportValueCheckLabelPrompt.Text = 'Value check';
            app.ImportValueCheckLabel = uilabel(app.DataInfoPanelGridLayout);
            app.ImportValueCheckLabel.Text = 'Unknown';
            app.NumberOfSamplesLabelPrompt = uilabel(app.DataInfoPanelGridLayout);
            app.NumberOfSamplesLabelPrompt.Text = '# of samples';
            app.NumberOfSamplesLabel = uilabel(app.DataInfoPanelGridLayout);
            app.NumberOfSamplesLabel.Text = '-';
            app.ImportedDataTable = uitable(app.InputDataTabGrid);
            app.ImportedDataTable.ColumnName = {'1'; '2'};
            app.ImportedDataTable.RowName = {};
            app.DisplayfulltableCheckBox = uicheckbox(app.InputDataTabGrid);
            app.DisplayfulltableCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayFullTableChanged, true);
            app.DisplayfulltableCheckBox.Tooltip = {'Display the full (slower) or only the beginning of the data imported (faster)'};
            app.DisplayfulltableCheckBox.Text = 'Display full table';
            app.ImportDataNextButton = uibutton(app.InputDataTabGrid, 'push');
            app.ImportDataNextButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataNextButtonPushed, true);
            app.ImportDataNextButton.Text = 'Next';
            app.ComparisonsSelectionTab = uitab(app.TabGroup);
            app.ComparisonsSelectionTab.Title = 'Comparisons Selection';
            app.ComparisonsSelectionTabGrid = uigridlayout(app.ComparisonsSelectionTab);
            app.CategoricallabelsfrominputfileforcomparisonsLabel = uilabel(app.ComparisonsSelectionTabGrid);
            app.CategoricallabelsfrominputfileforcomparisonsLabel.Text = 'Select a categorical label for the comparison';
            app.CategoricallabelsfrominputfileforcomparisonsLabel_2 = uilabel(app.ComparisonsSelectionTabGrid);
            app.CategoricallabelsfrominputfileforcomparisonsLabel_2.Text = 'Choose the comparison you wish to perform';
            app.CategoricalLabelsListBox = uilistbox(app.ComparisonsSelectionTabGrid);
            app.CategoricalLabelsListBox.Items = {};
            app.CategoricalLabelsListBox.ValueChangedFcn = createCallbackFcn(app, @CategoricalLabelsListBoxValueChanged, true);
            app.CategoricalLabelsListBox.Value = {};
            app.ShowComparisonsDropDown = uidropdown(app.ComparisonsSelectionTabGrid);
            app.ShowComparisonsDropDown.Items = {'Show all comparisons'};
            app.ShowComparisonsDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowComparisonsDropDownValueChanged, true);
            app.ShowComparisonsDropDown.Value = 'Show all comparisons';
            app.ComparisonsListBox = uilistbox(app.ComparisonsSelectionTabGrid);
            app.ComparisonsListBox.Items = {};
            app.ComparisonsListBox.ValueChangedFcn = createCallbackFcn(app, @ComparisonsListBoxButtonValueChanged, true);
            app.ComparisonsListBox.Value = {};
            app.DataSelectionBackButton = uibutton(app.ComparisonsSelectionTabGrid, 'push');
            app.DataSelectionBackButton.ButtonPushedFcn = createCallbackFcn(app, @DataSelectionBackButtonPushed, true);
            app.DataSelectionBackButton.Text = 'Back';
            app.DataSelectionNextButton = uibutton(app.ComparisonsSelectionTabGrid, 'push');
            app.DataSelectionNextButton.ButtonPushedFcn = createCallbackFcn(app, @DataSelectionNextButtonPushed, true);
            app.DataSelectionNextButton.Tooltip = {'Go the feature selection page'};
            app.DataSelectionNextButton.Text = 'Next';
            app.FeatureSelectionTab = uitab(app.TabGroup);
            app.FeatureSelectionTab.Title = 'Feature Selection';
            app.FeatureSelectionTabNavigationGrid = uigridlayout(app.FeatureSelectionTab);
            app.FeatureSelectionPanel = uipanel(app.FeatureSelectionTabNavigationGrid);
            app.GridLayout10 = uigridlayout(app.FeatureSelectionPanel);
            app.SelectAlgorithmsPanel = uipanel(app.GridLayout10);
            app.SelectAlgorithmsPanel.Title = 'Select Algorithms';
            app.GridLayout11 = uigridlayout(app.SelectAlgorithmsPanel);
            app.GridLayout14 = uigridlayout(app.GridLayout11);
            app.Image2_17 = uiimage(app.GridLayout14);
            app.Image2_17.Tooltip = {'The algorithms require Deep Learning Toolbox'};
            app.Image2_17.ImageSource = 'Question.png';
            app.Image2 = uiimage(app.GridLayout14);
            app.Image2.Tooltip = {'Rank features using Mutual Information criteria'};
            app.Image2.ImageSource = 'Question.png';
            app.Image2_2 = uiimage(app.GridLayout14);
            app.Image2_2.Tooltip = {'Rank features using Area between the empirical receiver operating characteristic (ROC) curve and the random classifier slope.  For more details see rankfeatures MATLAB function'};
            app.Image2_2.ImageSource = 'Question.png';
            app.Image2_3 = uiimage(app.GridLayout14);
            app.Image2_3.Tooltip = {'Rank features using Absolute value of the standardized u-statistic of a two-sample unpaired Wilcoxon test, also known as Mann-Whitney.  For more details see rankfeatures MATLAB function'};
            app.Image2_3.ImageSource = 'Question.png';
            app.Image2_4 = uiimage(app.GridLayout14);
            app.Image2_4.Tooltip = {' perform ReliefF analysis for classification using k nearest neighbors. For more details see relieff MATLAB function'};
            app.Image2_4.ImageSource = 'Question.png';
            app.Image2_5 = uiimage(app.GridLayout14);
            app.Image2_5.Tooltip = {'Rank features using TreeBagger decision trees algorithm.  For more details see TreeBagger MATLAB function'};
            app.Image2_5.ImageSource = 'Question.png';
            app.Image2_6 = uiimage(app.GridLayout14);
            app.Image2_6.Tooltip = {'Rank features using fitted binary classification decision tree.  For more details see fitctree MATLAB function '};
            app.Image2_6.ImageSource = 'Question.png';
            app.Image2_7 = uiimage(app.GridLayout14);
            app.Image2_7.Tooltip = {'Rank features using ensemble of decision trees and ''Bag'' aggregation.  For more details see fitcensemble MATLAB function '};
            app.Image2_7.ImageSource = 'Question.png';
            app.Image2_8 = uiimage(app.GridLayout14);
            app.Image2_8.Tooltip = {'Rank features using ensemble of decision trees and ''GentleBoost'' aggregation.  For more details see fitcensemble MATLAB function'};
            app.Image2_8.ImageSource = 'Question.png';
            app.Image2_9 = uiimage(app.GridLayout14);
            app.Image2_9.Tooltip = {'Rank features using ensemble of decision trees and ''RUSBoost'' aggregation.  For more details see fitcensemble MATLAB function'};
            app.Image2_9.ImageSource = 'Question.png';
            app.Image2_10 = uiimage(app.GridLayout14);
            app.Image2_10.Tooltip = {'Rank using sequential feature selection with kNN classification.  For more details see sequentialfs MATLAB function'};
            app.Image2_10.ImageSource = 'Question.png';
            app.Image2_11 = uiimage(app.GridLayout14);
            app.Image2_11.Tooltip = {'Rank using sequential feature selection with decision tree classification.  For more details see sequentialfs MATLAB function'};
            app.Image2_11.ImageSource = 'Question.png';
            app.Image2_12 = uiimage(app.GridLayout14);
            app.Image2_12.Tooltip = {'Rank using sequential feature selection with ensemble tree classification.  For more details see sequentialfs MATLAB function'};
            app.Image2_12.ImageSource = 'Question.png';
            app.Image2_13 = uiimage(app.GridLayout14);
            app.Image2_13.Tooltip = {'Rank using sequential feature selection with SVM classification.  For more details see sequentialfs MATLAB function'};
            app.Image2_13.ImageSource = 'Question.png';
            app.Image2_14 = uiimage(app.GridLayout14);
            app.Image2_14.Tooltip = {'Rank using sequential feature selection with quadratic LDA classification.  For more details see sequentialfs MATLAB function'};
            app.Image2_14.ImageSource = 'Question.png';
            app.AlgorithmsCheckboxes = uigridlayout(app.GridLayout11);
            app.AllCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.AllCheckBox.Tag = 'All';
            app.AllCheckBox.Text = 'All';
            app.MutualInfoCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.MutualInfoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.MutualInfoCheckBox.Tag = 'MI';
            app.MutualInfoCheckBox.Text = 'Ranking algorithm with MI';
            app.RocCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.RocCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.RocCheckBox.Tag = 'RankFeaturesROC';
            app.RocCheckBox.Text = 'Ranking algorithm with ROC';
            app.WilcoxonCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.WilcoxonCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.WilcoxonCheckBox.Tag = 'RankFeaturesWil';
            app.WilcoxonCheckBox.Text = 'Ranking algorithm with Wilcoxon';
            app.RankingusingReliefFalgorithmCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.RankingusingReliefFalgorithmCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.RankingusingReliefFalgorithmCheckBox.Tag = 'Relieff';
            app.RankingusingReliefFalgorithmCheckBox.Text = 'Ranking using ReliefF algorithm';
            app.RankingusingrandomforestalgTreeBaggerCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.RankingusingrandomforestalgTreeBaggerCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.RankingusingrandomforestalgTreeBaggerCheckBox.Tag = 'TreeBagger';
            app.RankingusingrandomforestalgTreeBaggerCheckBox.Text = 'Ranking using random forest alg. (TreeBagger)';
            app.RankingusingdecisiontreeclassificationCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.RankingusingdecisiontreeclassificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.RankingusingdecisiontreeclassificationCheckBox.Tag = 'Fitctree';
            app.RankingusingdecisiontreeclassificationCheckBox.Text = 'Ranking using decision tree classification';
            app.FictBagCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.FictBagCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.FictBagCheckBox.Tag = 'ensembleBag';
            app.FictBagCheckBox.Text = 'Trees ensemble with ''Bag'' aggregation';
            app.FictGentleBoostCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.FictGentleBoostCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.FictGentleBoostCheckBox.Tag = 'ensembleGentleBoost';
            app.FictGentleBoostCheckBox.Text = 'Trees ensemble with ''GentleBoost'' aggregation';
            app.FictRusBoostCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.FictRusBoostCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.FictRusBoostCheckBox.Tag = 'ensembleRUSBoost';
            app.FictRusBoostCheckBox.Text = 'Trees ensemble with ''RUSBoost'' aggregation';
            app.KnnCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.KnnCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.KnnCheckBox.Tag = 'Sequential_knn';
            app.KnnCheckBox.Text = 'Sequential algorithm with kNN';
            app.SeqTreeCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.SeqTreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.SeqTreeCheckBox.Tag = 'Sequential_tree';
            app.SeqTreeCheckBox.Text = 'Sequential algorithm with decision tree';
            app.SeqEnsblCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.SeqEnsblCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.SeqEnsblCheckBox.Tag = 'Sequential_ensbl';
            app.SeqEnsblCheckBox.Text = 'Sequential algorithm with tree ensemble';
            app.SeqSvmCheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.SeqSvmCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.SeqSvmCheckBox.Tag = 'Sequential_svm';
            app.SeqSvmCheckBox.Text = 'Sequential algorithm with SVM';
            app.SeqQuadrLDACheckBox = uicheckbox(app.AlgorithmsCheckboxes);
            app.SeqQuadrLDACheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsPanelSelectionChanged, true);
            app.SeqQuadrLDACheckBox.Tag = 'Sequential_quadrLDA';
            app.SeqQuadrLDACheckBox.Text = 'Sequential algorithm with quadratic discriminant analysis (QDA)';
            app.CrossValidationButtonGroup = uibuttongroup(app.GridLayout10);
            app.CrossValidationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CrossValidationButtonValueChanged, true);
            app.CrossValidationButtonGroup.Title = 'Cross-Validation';
            app.KfoldButton = uiradiobutton(app.CrossValidationButtonGroup);
            app.KfoldButton.Tag = 'K-fold';
            app.KfoldButton.Text = 'K-fold';
            app.KfoldButton.Value = true;
            app.KSpinnerLabel = uilabel(app.CrossValidationButtonGroup);
            app.KSpinnerLabel.Text = 'K = ';
            app.KSpinner = uispinner(app.CrossValidationButtonGroup);
            app.KSpinner.Limits = [1 20];
            app.KSpinner.ValueChangedFcn = createCallbackFcn(app, @KfoldKValueChanged, true);
            app.KSpinner.Value = 5;
            app.HoldoutButton = uiradiobutton(app.CrossValidationButtonGroup);
            app.HoldoutButton.Tag = 'Holdout';
            app.HoldoutButton.Text = 'Hold out';
            app.LeaveoneoutButton = uiradiobutton(app.CrossValidationButtonGroup);
            app.LeaveoneoutButton.Tag = 'Leaveoneout';
            app.LeaveoneoutButton.Text = 'Leave one out';
            app.ResubButtonButton = uiradiobutton(app.CrossValidationButtonGroup);
            app.ResubButtonButton.Tag = 'Resubstitution';
            app.ResubButtonButton.Text = 'Resubstitution';
            app.Image2_16 = uiimage(app.GridLayout10);
            app.Image2_16.Tooltip = {'Parallel computing settings requires installation of Parallel Computing toolbox.'};
            app.Image2_16.ImageSource = 'Question.png';
            app.iterationsforSequentialalgPanel = uipanel(app.GridLayout10);
            app.iterationsforSequentialalgPanel.Title = '# iterations for Sequential alg.';
            app.GridLayout16 = uigridlayout(app.iterationsforSequentialalgPanel);
            app.IterationsLabel = uilabel(app.GridLayout16);
            app.IterationsLabel.Text = 'Iterations =';
            app.IterationsDropDown = uidropdown(app.GridLayout16);
            app.IterationsDropDown.Items = {'1', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50'};
            app.IterationsDropDown.ValueChangedFcn = createCallbackFcn(app, @IterationsDropDownValueChanged, true);
            app.IterationsDropDown.Value = '5';
            app.Image2_15 = uiimage(app.GridLayout10);
            app.Image2_15.Tooltip = {'Sequential algorithms have random component. Choose larger N  for generalized  result. Note, takes long time to compute.'};
            app.Image2_15.ImageSource = 'Question.png';
            app.SelectionSummaryPanel = uipanel(app.GridLayout10);
            app.SelectionSummaryPanel.Title = 'Selection Summary';
            app.GridLayout12 = uigridlayout(app.SelectionSummaryPanel);
            app.SummaryTextArea = uitextarea(app.GridLayout12);
            app.SummaryTextArea.ValueChangedFcn = createCallbackFcn(app, @ComparisonsListBoxButtonValueChanged, true);
            app.SummaryTextArea.Editable = 'off';
            app.ParallelComputingButtonGroup = uibuttongroup(app.GridLayout10);
            app.ParallelComputingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParallelComputingButtonValueChanged, true);
            app.ParallelComputingButtonGroup.Title = 'Parallel Computing';
            app.OnButton = uiradiobutton(app.ParallelComputingButtonGroup);
            app.OnButton.Tag = 'on';
            app.OnButton.Text = 'On';
            app.OffButton = uiradiobutton(app.ParallelComputingButtonGroup);
            app.OffButton.Tag = 'off';
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.FeatureSelectionBackButton = uibutton(app.FeatureSelectionTabNavigationGrid, 'push');
            app.FeatureSelectionBackButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureSelectionBackButtonPushed, true);
            app.FeatureSelectionBackButton.Text = 'Back';
            app.SetcomputersleepsettingstoNeverLabel = uilabel(app.FeatureSelectionTabNavigationGrid);
            app.SetcomputersleepsettingstoNeverLabel.Text = 'Set computer sleep settings to "Never" ';
            app.StayconnectedtoapowersourceLabel = uilabel(app.FeatureSelectionTabNavigationGrid);
            app.StayconnectedtoapowersourceLabel.Text = 'Stay connected to a power source';
            app.RunButton = uibutton(app.FeatureSelectionTabNavigationGrid, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Perform the feature selection algorithms'};
            app.RunButton.Text = 'Run';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTabGrid = uigridlayout(app.ResultsTab);
            app.ComparisonPanel = uipanel(app.ResultsTabGrid);
            app.ComparisonPanel.Title = 'Comparison';
            app.ComparisonPanelGrid = uigridlayout(app.ComparisonPanel);
            app.ViewResultsDropDown = uidropdown(app.ComparisonPanelGrid);
            app.ViewResultsDropDown.Items = {'Ranked features', 'Ranked cell data'};
            app.ViewResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewResultsDropDownValueChanged, true);
            app.ViewResultsDropDown.Tooltip = {'Choose between viewing ranked cell data or ranked feature data'};
            app.ViewResultsDropDown.Value = 'Ranked features';
            app.ExportResultsButton = uibutton(app.ComparisonPanelGrid, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportResultsButton.Tooltip = {'Export the current data being viewed to a Microsoft Excel file'};
            app.ExportResultsButton.Text = 'Export Results';
            app.ResultsTable = uitable(app.ComparisonPanelGrid);
            app.ResultsTable.ColumnName = {'1'; '2'};
            app.ResultsTable.RowName = {};
            app.ResultsTable.CellSelectionCallback = createCallbackFcn(app, @ResultsTableCellSelectionChanged, true);
            app.GridLayout9 = uigridlayout(app.ComparisonPanelGrid);
            app.PlotPreviousTwo = uibutton(app.GridLayout9, 'push');
            app.PlotPreviousTwo.ButtonPushedFcn = createCallbackFcn(app, @PlotPreviousTwoButtonPushed, true);
            app.PlotPreviousTwo.Icon = 'SelectPreviousTwo.png';
            app.PlotPreviousTwo.Enable = 'off';
            app.PlotPreviousTwo.Tooltip = {'Move to the previous two features on the list'};
            app.PlotPreviousTwo.Text = '';
            app.PlotNextTwo = uibutton(app.GridLayout9, 'push');
            app.PlotNextTwo.ButtonPushedFcn = createCallbackFcn(app, @PlotNextTwoButtonPushed, true);
            app.PlotNextTwo.Icon = 'SelectNextTwo.png';
            app.PlotNextTwo.Enable = 'off';
            app.PlotNextTwo.Tooltip = {'Move to the next two features in the list'};
            app.PlotNextTwo.Text = '';
            app.LastResultPage = uibutton(app.GridLayout9, 'push');
            app.LastResultPage.ButtonPushedFcn = createCallbackFcn(app, @LastResultPageButtonPushed, true);
            app.LastResultPage.Icon = 'Left.png';
            app.LastResultPage.Enable = 'off';
            app.LastResultPage.Tooltip = {'Move to previous page'};
            app.LastResultPage.Text = '';
            app.ResultPageLabel = uilabel(app.GridLayout9);
            app.ResultPageLabel.Text = '1/1';
            app.NextResultPage = uibutton(app.GridLayout9, 'push');
            app.NextResultPage.ButtonPushedFcn = createCallbackFcn(app, @NextResultPageButtonPushed, true);
            app.NextResultPage.Icon = 'Right.png';
            app.NextResultPage.Enable = 'off';
            app.NextResultPage.Tooltip = {'Move to next page'};
            app.NextResultPage.Text = '';
            app.PlotPanel = uipanel(app.ResultsTabGrid);
            app.PlotPanel.Title = 'Plot';
            app.GridLayout8 = uigridlayout(app.PlotPanel);
            app.ResultsUIAxes = uiaxes(app.GridLayout8);
            xlabel(app.ResultsUIAxes, 'Select a feature')
            ylabel(app.ResultsUIAxes, 'Select a feature')
            app.ResultsUIAxes.XLim = [1 2];
            app.YFeatureActionsGrid = uigridlayout(app.GridLayout8);
            app.AddYFeatureToSelected = uibutton(app.YFeatureActionsGrid, 'push');
            app.AddYFeatureToSelected.ButtonPushedFcn = createCallbackFcn(app, @AddYFeatureToSelectedButtonPushed, true);
            app.AddYFeatureToSelected.Icon = 'AddFeatureToSelected.png';
            app.AddYFeatureToSelected.Enable = 'off';
            app.AddYFeatureToSelected.Tooltip = {'Add this feature to selected features'};
            app.AddYFeatureToSelected.Text = '';
            app.RemoveYFeatureFromSelected = uibutton(app.YFeatureActionsGrid, 'push');
            app.RemoveYFeatureFromSelected.ButtonPushedFcn = createCallbackFcn(app, @RemoveYFeatureFromSelectedButtonPushed, true);
            app.RemoveYFeatureFromSelected.Icon = 'RemoveFeatureFromSelected.png';
            app.RemoveYFeatureFromSelected.Enable = 'off';
            app.RemoveYFeatureFromSelected.Tooltip = {'Remove this feature from selected features'};
            app.RemoveYFeatureFromSelected.Text = '';
            app.XFeatureActionsGrid = uigridlayout(app.GridLayout8);
            app.RemoveXYFeatures = uibutton(app.XFeatureActionsGrid, 'push');
            app.RemoveXYFeatures.ButtonPushedFcn = createCallbackFcn(app, @RemoveXYFeaturesButtonPushed, true);
            app.RemoveXYFeatures.Icon = 'RemoveAllFeatureButtonIcon.png';
            app.RemoveXYFeatures.Tooltip = {'Clear the plot'};
            app.RemoveXYFeatures.Text = '';
            app.LogTransformationButton = uibutton(app.XFeatureActionsGrid, 'state');
            app.LogTransformationButton.ValueChangedFcn = createCallbackFcn(app, @LogTransformationButtonPushed, true);
            app.LogTransformationButton.Tooltip = {'Perform log2 transformation for the plotted data (does not affect the actual data)'};
            app.LogTransformationButton.Icon = 'LogTransformation.png';
            app.LogTransformationButton.Text = '';
            app.PlotStyleSettings = uibutton(app.XFeatureActionsGrid, 'state');
            app.PlotStyleSettings.ValueChangedFcn = createCallbackFcn(app, @PlotStyleSettingsPlotStyleSettingsPushed, true);
            app.PlotStyleSettings.Tooltip = {'Change marker properties'};
            app.PlotStyleSettings.Icon = 'PlotStyleSettings.png';
            app.PlotStyleSettings.Text = '';
            app.AddXFeatureToSelected = uibutton(app.XFeatureActionsGrid, 'push');
            app.AddXFeatureToSelected.ButtonPushedFcn = createCallbackFcn(app, @AddXFeatureToSelectedButtonPushed, true);
            app.AddXFeatureToSelected.Icon = 'AddFeatureToSelected.png';
            app.AddXFeatureToSelected.Enable = 'off';
            app.AddXFeatureToSelected.Tooltip = {'Add this feature to selected features'};
            app.AddXFeatureToSelected.Text = '';
            app.RemoveXFeatureFromSelected = uibutton(app.XFeatureActionsGrid, 'push');
            app.RemoveXFeatureFromSelected.ButtonPushedFcn = createCallbackFcn(app, @RemoveXFeatureFromSelectedButtonPushed, true);
            app.RemoveXFeatureFromSelected.Icon = 'RemoveFeatureFromSelected.png';
            app.RemoveXFeatureFromSelected.Enable = 'off';
            app.RemoveXFeatureFromSelected.Tooltip = {'Remove this feature from selected features'};
            app.RemoveXFeatureFromSelected.Text = '';
            app.PredictiveImportancePlotButton = uibutton(app.XFeatureActionsGrid, 'push');
            app.PredictiveImportancePlotButton.ButtonPushedFcn = createCallbackFcn(app, @PredictiveImportancePlotButtonPushed, true);
            app.PredictiveImportancePlotButton.Tooltip = {'Plots a bar graph of averaged scores for the ranked features'};
            app.PredictiveImportancePlotButton.Text = 'Predictive Importance Plot';
            app.SelectedFeaturesPanel = uipanel(app.ResultsTabGrid);
            app.SelectedFeaturesPanel.Title = 'Selected Features';
            app.GridLayout6 = uigridlayout(app.SelectedFeaturesPanel);
            app.FeaturesToClusterWaysButtonGroup = uibuttongroup(app.GridLayout6);
            app.FeaturesToClusterWaysButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FeaturesToClusterWaysButtonGroupSelectionChanged, true);
            app.AllButton = uiradiobutton(app.FeaturesToClusterWaysButtonGroup);
            app.AllButton.Tag = 'all';
            app.AllButton.Tooltip = {'Select all features from the result'};
            app.AllButton.Text = 'All';
            app.PercentButton = uiradiobutton(app.FeaturesToClusterWaysButtonGroup);
            app.PercentButton.Tag = 'percentage';
            app.PercentButton.Tooltip = {''};
            app.PercentButton.Text = 'Top _ %';
            app.Image2_19 = uiimage(app.FeaturesToClusterWaysButtonGroup);
            app.Image2_19.Tooltip = {'Select the top X% of the features from ranked result'};
            app.Image2_19.ImageSource = 'Question.png';
            app.PercentageToClusterDropDown = uidropdown(app.FeaturesToClusterWaysButtonGroup);
            app.PercentageToClusterDropDown.Items = {'1', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.PercentageToClusterDropDown.ValueChangedFcn = createCallbackFcn(app, @PercentageToClusterDropDownValueChanged, true);
            app.PercentageToClusterDropDown.Tooltip = {'Choose '};
            app.PercentageToClusterDropDown.Value = '5';
            app.CustomButton = uiradiobutton(app.FeaturesToClusterWaysButtonGroup);
            app.CustomButton.Tag = 'custom';
            app.CustomButton.Text = 'Custom';
            app.CustomButton.Value = true;
            app.Image2_18 = uiimage(app.FeaturesToClusterWaysButtonGroup);
            app.Image2_18.Tooltip = {'Custom select features from ranked results or the plot'};
            app.Image2_18.ImageSource = 'Question.png';
            app.FeaturesToClusterListBox = uilistbox(app.GridLayout6);
            app.FeaturesToClusterListBox.Items = {};
            app.FeaturesToClusterListBox.Multiselect = 'on';
            app.FeaturesToClusterListBox.ValueChangedFcn = createCallbackFcn(app, @FeaturesToClusterListBoxSelectionChanged, true);
            app.FeaturesToClusterListBox.Value = {};
            app.AddCurrentFeatureButton = uibutton(app.GridLayout6, 'push');
            app.AddCurrentFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @AddCurrentFeatureButtonPushed, true);
            app.AddCurrentFeatureButton.Icon = 'AddCurrentFeatureButtonIcon.png';
            app.AddCurrentFeatureButton.Enable = 'off';
            app.AddCurrentFeatureButton.Tooltip = {'Add the feature(s) selected in the result table to the selected features'};
            app.AddCurrentFeatureButton.Text = '';
            app.RemoveCurrentFeatureButton = uibutton(app.GridLayout6, 'push');
            app.RemoveCurrentFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCurrentFeatureButtonPushed, true);
            app.RemoveCurrentFeatureButton.Icon = 'RemoveCurrentFeatureButtonIcon.png';
            app.RemoveCurrentFeatureButton.Enable = 'off';
            app.RemoveCurrentFeatureButton.Tooltip = {'Remove the feature(s) selected from the list'};
            app.RemoveCurrentFeatureButton.Text = '';
            app.RemoveAllFeatureButton = uibutton(app.GridLayout6, 'push');
            app.RemoveAllFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllFeatureButtonPushed, true);
            app.RemoveAllFeatureButton.Icon = 'RemoveAllFeatureButtonIcon.png';
            app.RemoveAllFeatureButton.Enable = 'off';
            app.RemoveAllFeatureButton.Tooltip = {'Clear the list'};
            app.RemoveAllFeatureButton.Text = '';
            app.Button = uibutton(app.GridLayout6, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @tsneButtonPushed, true);
            app.Button.Icon = 'tsne.png';
            app.Button.Text = '';
            app.CopyCurrentFeatureButton = uibutton(app.GridLayout6, 'push');
            app.CopyCurrentFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @CopyCurrentFeatureButtonPushed, true);
            app.CopyCurrentFeatureButton.Icon = 'CopyCurrentFeatureButtonIcon.png';
            app.CopyCurrentFeatureButton.Tooltip = {'Copy the selected features in plain text'};
            app.CopyCurrentFeatureButton.Text = '';
            app.ResultsNavigationGrid = uigridlayout(app.ResultsTabGrid);
            app.ResultsBackButton = uibutton(app.ResultsNavigationGrid, 'push');
            app.ResultsBackButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsBackButtonPushed, true);
            app.ResultsBackButton.Text = 'Back';
            app.NextButton = uibutton(app.ResultsNavigationGrid, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Go to clustering page'};
            app.NextButton.Text = 'Next';
            app.ClusteringTab = uitab(app.TabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.ClusteringTabGrid = uigridlayout(app.ClusteringTab);
            app.HierarchicalClusteringSettingsPanel = uipanel(app.ClusteringTabGrid);
            app.HierarchicalClusteringSettingsPanel.Title = 'Hierarchical Clustering Settings';
            app.GridLayout17 = uigridlayout(app.HierarchicalClusteringSettingsPanel);
            app.Image16 = uiimage(app.GridLayout17);
            app.Image16.Tooltip = {'Similarity measure for clustering the samples. Corresponds to the column distance. See clustergram MATLAB function for more detail. '};
            app.Image16.ImageSource = 'Question.png';
            app.SamplessimilarityDropDownLabel = uilabel(app.GridLayout17);
            app.SamplessimilarityDropDownLabel.Text = 'Samples similarity';
            app.SamplessimilarityDropDown = uidropdown(app.GridLayout17);
            app.SamplessimilarityDropDown.Items = {'Euclidean', 'Squared Euclidean', 'Standardized Euclidean', 'Mahalanobis', 'City Block Minkowski', 'Chebychev', 'Cosine', 'Correlation', 'Hamming', 'Jaccard', 'Spearman'};
            app.SamplessimilarityDropDown.ItemsData = {'euclidean', 'squaredeuclidean', 'seuclidean', 'mahalanobis', 'cityblock minkowski', 'chebychev', 'cosine', 'correlation', 'hamming', 'jaccard', 'spearman'};
            app.SamplessimilarityDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplessimilarityDropDownValueChanged, true);
            app.SamplessimilarityDropDown.Value = 'spearman';
            app.Image16_2 = uiimage(app.GridLayout17);
            app.Image16_2.Tooltip = {'Similarity measure for clustering the features. Corresponds to the row distance. See clustergram MATLAB function for more detail. '};
            app.Image16_2.ImageSource = 'Question.png';
            app.FeaturessimilarityDropDownLabel = uilabel(app.GridLayout17);
            app.FeaturessimilarityDropDownLabel.Text = 'Features similarity';
            app.FeaturessimilarityDropDown = uidropdown(app.GridLayout17);
            app.FeaturessimilarityDropDown.Items = {'Euclidean', 'Squared Euclidean', 'Standardized Euclidean', 'Mahalanobis', 'City Block Minkowski', 'Chebychev', 'Cosine', 'Correlation', 'Hamming', 'Jaccard', 'Spearman'};
            app.FeaturessimilarityDropDown.ItemsData = {'euclidean', 'squaredeuclidean', 'seuclidean', 'mahalanobis', 'cityblock minkowski', 'chebychev', 'cosine', 'correlation', 'hamming', 'jaccard', 'spearman'};
            app.FeaturessimilarityDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturessimilarityDropDownValueChanged, true);
            app.FeaturessimilarityDropDown.Value = 'euclidean';
            app.Image16_4 = uiimage(app.GridLayout17);
            app.Image16_4.Tooltip = {'Method for connecting similar clusters'};
            app.Image16_4.ImageSource = 'Question.png';
            app.LinkageDropDownLabel = uilabel(app.GridLayout17);
            app.LinkageDropDownLabel.Text = 'Linkage';
            app.LinkageDropDown = uidropdown(app.GridLayout17);
            app.LinkageDropDown.Items = {'Average', 'Centroid', 'Complete', 'Median', 'Single', 'Ward', 'Weighted'};
            app.LinkageDropDown.ItemsData = {'average', 'centroid', 'complete', 'median', 'single', 'ward', 'weighted'};
            app.LinkageDropDown.ValueChangedFcn = createCallbackFcn(app, @LinkageDropDownValueChanged, true);
            app.LinkageDropDown.Value = 'average';
            app.Image16_3 = uiimage(app.GridLayout17);
            app.Image16_3.Tooltip = {'Colour combinations for the heatmap that accompanies the hierarchical clustering.'};
            app.Image16_3.ImageSource = 'Question.png';
            app.ColourmapDropDownLabel = uilabel(app.GridLayout17);
            app.ColourmapDropDownLabel.Text = 'Colour map';
            app.ColourmapDropDown = uidropdown(app.GridLayout17);
            app.ColourmapDropDown.Items = {'RedBlue', 'Parula', 'Jet', 'HSV', 'Hot', 'Cool', 'Spring', 'Summer', 'Autumn', 'Winter', 'Gray', 'Bone', 'Copper', 'Pink'};
            app.ColourmapDropDown.ItemsData = {'myColourMap_RedBlue', 'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.ColourmapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourmapDropDownValueChanged, true);
            app.ColourmapDropDown.Value = 'myColourMap_RedBlue';
            app.Image16_5 = uiimage(app.GridLayout17);
            app.Image16_5.Tooltip = {'Range for the colour distribution. For more graded differences, select higher range.'};
            app.Image16_5.ImageSource = 'Question.png';
            app.DisplayrangeSpinnerLabel = uilabel(app.GridLayout17);
            app.DisplayrangeSpinnerLabel.Text = 'Display range';
            app.DisplayrangeSpinner = uispinner(app.GridLayout17);
            app.DisplayrangeSpinner.Limits = [1 10];
            app.DisplayrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @DisplayrangeSpinnerValueChanged, true);
            app.DisplayrangeSpinner.Value = 3;
            app.Image16_6 = uiimage(app.GridLayout17);
            app.Image16_6.Tooltip = {'Scale colour symmetrically around zero'};
            app.Image16_6.ImageSource = 'Question.png';
            app.SymmetricDropDownLabel = uilabel(app.GridLayout17);
            app.SymmetricDropDownLabel.Text = 'Symmetric';
            app.SymmetricDropDown = uidropdown(app.GridLayout17);
            app.SymmetricDropDown.Items = {'Yes', 'No'};
            app.SymmetricDropDown.ItemsData = {'1', '0'};
            app.SymmetricDropDown.ValueChangedFcn = createCallbackFcn(app, @SymmetricDropDownValueChanged, true);
            app.SymmetricDropDown.Value = '1';
            app.ComparisonLabel = uilabel(app.GridLayout17);
            app.ComparisonLabel.Text = 'Comparison: ';
            app.BoxplotButton = uibutton(app.GridLayout17, 'push');
            app.BoxplotButton.ButtonPushedFcn = createCallbackFcn(app, @BoxplotButtonPushed, true);
            app.BoxplotButton.Text = 'Boxplot';
            app.GenerateClustergram = uibutton(app.ClusteringTabGrid, 'push');
            app.GenerateClustergram.ButtonPushedFcn = createCallbackFcn(app, @GenerateClustergramPushed, true);
            app.GenerateClustergram.Tooltip = {'Generate clustergram'};
            app.GenerateClustergram.Text = 'Generate';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutTabGrid = uigridlayout(app.AboutTab);
            app.bg = uiimage(app.AboutTabGrid);
            app.bg.ImageSource = 'bg.png';
            app.MolecularFeaSTLabel_2 = uilabel(app.AboutTabGrid);
            app.MolecularFeaSTLabel_2.Text = '   MolecularFeaST';
            app.AboutLabel = uilabel(app.AboutTabGrid);
            app.AboutLabel.Text = 'About';
            app.VersionLabel = uilabel(app.AboutTabGrid);
            app.VersionLabel.Text = 'Version';
            app.HelpLabel = uilabel(app.AboutTabGrid);
            app.HelpLabel.Text = 'Help';
            app.ToolboxesLabel = uilabel(app.AboutTabGrid);
            app.ToolboxesLabel.Text = 'Toolboxes';
            app.AboutGrid = uigridlayout(app.AboutTabGrid);
            app.Label = uilabel(app.AboutGrid);
            app.Label.Text = 'MolecularFeaST is a tool that assists the searching for the most accurate predictors. It uses machine learning to rank the features based on their relevance to some given labels and support multiple algorithms.';
            app.VersionGrid = uigridlayout(app.AboutTabGrid);
            app.UpdateButton = uibutton(app.VersionGrid, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Check for Update';
            app.CurrentVersion = uilabel(app.VersionGrid);
            app.CurrentVersion.Text = 'version number';
            app.UpdatenotesLabel = uilabel(app.VersionGrid);
            app.UpdatenotesLabel.Visible = 'off';
            app.UpdatenotesLabel.Text = 'Update notes';
            app.HelpGrid = uigridlayout(app.AboutTabGrid);
            app.UserManualButton = uibutton(app.HelpGrid, 'push');
            app.UserManualButton.ButtonPushedFcn = createCallbackFcn(app, @UserManualButtonPushed, true);
            app.UserManualButton.Text = 'User Manual';
            app.TollboxGrid = uigridlayout(app.AboutTabGrid);
            app.BioinformaticsToolboxLamp = uilamp(app.TollboxGrid);
            app.BioinformaticsToolboxLampLabel = uilabel(app.TollboxGrid);
            app.BioinformaticsToolboxLampLabel.Text = 'Bioinformatics Toolbox';
            app.DeepLearningToolboxLamp = uilamp(app.TollboxGrid);
            app.DeepLearningToolboxLampLabel = uilabel(app.TollboxGrid);
            app.DeepLearningToolboxLampLabel.Text = 'Deep Learning Toolbox';
            app.ParallelComputingToolboxLamp = uilamp(app.TollboxGrid);
            app.ParallelComputingToolboxLampLabel = uilabel(app.TollboxGrid);
            app.ParallelComputingToolboxLampLabel.Text = 'Parallel Computing Toolbox';
            app.StatisticsandMachineLearningToolboxLamp = uilamp(app.TollboxGrid);
            app.StatisticsandMachineLearningToolboxLamp_2Label = uilabel(app.TollboxGrid);
            app.StatisticsandMachineLearningToolboxLamp_2Label.Text = 'Statistics and Machine Learning Toolbox';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = molecularFeaST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end